classdef DoubleTuned_CircuitDesignHelper_V1p1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplicationsPanel               matlab.ui.container.Panel
        DoubleTunedCableBalunLabel      matlab.ui.control.Label
        DoubleTunedTrapCircuitLabel     matlab.ui.control.Label
        DoubleTunedRFCoilLabel          matlab.ui.control.Label
        Image4_2                        matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        ByChenhaoSunPhDchenhaosunyaleeduVersion11Label  matlab.ui.control.Label
        ReCenterYlimitButton            matlab.ui.control.Button
        Step3SelectandFineTunetheLCCNetworkPanel  matlab.ui.container.Panel
        C2lossOhmEditField              matlab.ui.control.NumericEditField
        C2lossOhmEditFieldLabel         matlab.ui.control.Label
        L1lossOhmEditField              matlab.ui.control.NumericEditField
        L1lossOhmEditFieldLabel         matlab.ui.control.Label
        C1lossOhmEditField              matlab.ui.control.NumericEditField
        C1lossOhmEditFieldLabel         matlab.ui.control.Label
        L1nHSlider                      matlab.ui.control.Slider
        L1nHSliderLabel                 matlab.ui.control.Label
        L1nHEditField                   matlab.ui.control.NumericEditField
        L1nHEditFieldLabel              matlab.ui.control.Label
        C2pFSlider                      matlab.ui.control.Slider
        C2pFSliderLabel                 matlab.ui.control.Label
        C2pFEditField                   matlab.ui.control.NumericEditField
        C2pFEditFieldLabel              matlab.ui.control.Label
        C1pFSlider                      matlab.ui.control.Slider
        C1pFSliderLabel                 matlab.ui.control.Label
        C1pFEditField                   matlab.ui.control.NumericEditField
        C1pFEditFieldLabel              matlab.ui.control.Label
        ReCenterL1Button                matlab.ui.control.Button
        ReCenterC2Button                matlab.ui.control.Button
        ReCenterC1Button                matlab.ui.control.Button
        ClickHeretoPlotLCCNetworkImpedanceButton  matlab.ui.control.Button
        LCCType2Panel                   matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        DoubleTunedNetworkButtonGroup   matlab.ui.container.ButtonGroup
        LCCType2Button                  matlab.ui.control.RadioButton
        LCCType1Button                  matlab.ui.control.RadioButton
        LCCType1Panel                   matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        Step2LoadSourceImpedancePanel   matlab.ui.container.Panel
        PlotMeasuredSourceImpedanceButton  matlab.ui.control.Button
        VNACoilFileNameEditField        matlab.ui.control.EditField
        VNACoilFileNameEditFieldLabel   matlab.ui.control.Label
        Step2CreateSourceImpedancePanel  matlab.ui.container.Panel
        PlotSourceImpedanceButton       matlab.ui.control.Button
        SourceInductanceuHorCapacitancepFEditField  matlab.ui.control.NumericEditField
        SourceInductanceorCapacitanceLabel  matlab.ui.control.Label
        SourceResistanceOhmEditField    matlab.ui.control.NumericEditField
        SourceResistanceOhmEditFieldLabel  matlab.ui.control.Label
        SourceTypeDropDown              matlab.ui.control.DropDown
        SourceTypeDropDownLabel         matlab.ui.control.Label
        ofStepsEditField                matlab.ui.control.NumericEditField
        ofStepsEditFieldLabel           matlab.ui.control.Label
        FrequencyMHzEndEditField        matlab.ui.control.NumericEditField
        FrequencyMHzEndEditFieldLabel   matlab.ui.control.Label
        FrequencyMHzStartEditField      matlab.ui.control.NumericEditField
        FrequencyMHzStartEditFieldLabel  matlab.ui.control.Label
        Step1LarmorFrequencyTablePanel  matlab.ui.container.Panel
        FieldStrengthTEditField         matlab.ui.control.NumericEditField
        FieldStrengthTEditFieldLabel    matlab.ui.control.Label
        Color31PColorPicker             matlab.ui.control.ColorPicker
        Color31PColorPickerLabel        matlab.ui.control.Label
        Color19FColorPicker             matlab.ui.control.ColorPicker
        Color19FColorPickerLabel        matlab.ui.control.Label
        Color3HeColorPicker             matlab.ui.control.ColorPicker
        Color3HeColorPickerLabel        matlab.ui.control.Label
        Color129XeColorPicker           matlab.ui.control.ColorPicker
        Color129XeColorPickerLabel      matlab.ui.control.Label
        Color23NaColorPicker            matlab.ui.control.ColorPicker
        Color23NaColorPickerLabel       matlab.ui.control.Label
        Color13CColorPicker             matlab.ui.control.ColorPicker
        Color13CColorPickerLabel        matlab.ui.control.Label
        Color2HColorPicker              matlab.ui.control.ColorPicker
        Color2HColorPickerLabel         matlab.ui.control.Label
        Color1HColorPicker              matlab.ui.control.ColorPicker
        Color1HColorPickerLabel         matlab.ui.control.Label
        Color17OColorPicker             matlab.ui.control.ColorPicker
        Color17OColorPickerLabel        matlab.ui.control.Label
        HMHzEditField                   matlab.ui.control.NumericEditField
        HMHzEditFieldLabel              matlab.ui.control.Label
        HMHzEditField_2                 matlab.ui.control.NumericEditField
        HMHzEditField_2Label            matlab.ui.control.Label
        FMHzEditField                   matlab.ui.control.NumericEditField
        FMHzEditFieldLabel              matlab.ui.control.Label
        HeMHzEditField                  matlab.ui.control.NumericEditField
        HeMHzEditFieldLabel             matlab.ui.control.Label
        PMHzEditField                   matlab.ui.control.NumericEditField
        PMHzEditFieldLabel              matlab.ui.control.Label
        XeMHzEditField                  matlab.ui.control.NumericEditField
        XeMHzEditFieldLabel             matlab.ui.control.Label
        NaMHzEditField                  matlab.ui.control.NumericEditField
        NaMHzEditFieldLabel             matlab.ui.control.Label
        CMHzEditField                   matlab.ui.control.NumericEditField
        CMHzEditFieldLabel              matlab.ui.control.Label
        OMHzEditField                   matlab.ui.control.NumericEditField
        OMHzLabel                       matlab.ui.control.Label
        Mark19FCheckBox                 matlab.ui.control.CheckBox
        Mark3HeCheckBox                 matlab.ui.control.CheckBox
        Mark129XeCheckBox               matlab.ui.control.CheckBox
        Mark23NaCheckBox                matlab.ui.control.CheckBox
        Mark13CCheckBox                 matlab.ui.control.CheckBox
        Mark2HCheckBox                  matlab.ui.control.CheckBox
        Mark31PCheckBox                 matlab.ui.control.CheckBox
        Mark1HCheckBox                  matlab.ui.control.CheckBox
        Mark17OCheckBox                 matlab.ui.control.CheckBox
        YaxisLimitSlider                matlab.ui.control.Slider
        YaxisLimitSliderLabel           matlab.ui.control.Label
        YaxisLimitEditField             matlab.ui.control.NumericEditField
        YaxisLimitEditFieldLabel        matlab.ui.control.Label
        ClickheretoInitiateOrResetButton  matlab.ui.control.Button
        DoubleTunedCircuitDesignHelperforMRRFCoilTrapBalunLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Larmor freq
        FieldStrength 
        LF_17O
        LF_2H
        LF_13C
        LF_23Na
        LF_129Xe
        LF_31P
        LF_3He
        LF_19F
        LF_1H

        ind17O
        ind2H
        ind13C
        ind23Na
        ind129Xe
        ind31P
        ind3He
        ind19F
        ind1H
        
        % freq start and end
        Freq_S
        Freq_E
        Freq_NofS
        F1
        max1
        min1
        ErrLimit
        % Source impedance parameters
        Z1
       
        R
        IndCap
        
        % two UI Axis plot handles
        R1 
        R2

        Zc1
        Zl1
        Zc2
        LCC_React

        M17O
        M2H
        M13C
        M23Na
        M129Xe
        M31P
        M3He
        M19F
        M1H

        MD17O
        MD2H
        MD13C
        MD23Na
        MD129Xe
        MD31P
        MD3He
        MD19F
        MD1H

        T17O
        T2H
        T13C
        T23Na
        T129Xe
        T31P
        T3He
        T19F
        T1H

        TD17O
        TD2H
        TD13C
        TD23Na
        TD129Xe
        TD31P
        TD3He
        TD19F
        TD1H

        XL_Delta_17O
        XL_Delta_2H
        XL_Delta_13C
        XL_Delta_23Na
        XL_Delta_129Xe
        XL_Delta_31P
        XL_Delta_3He
        XL_Delta_19F
        XL_Delta_1H

        X_off = 0.95;
        Y_off = 100;


    end
    
    methods (Access = private)
        
        function [] = Update_LCC_React(app)
            
            % app.Zc1 = 1./(1i*2*pi*app.F1*1e6*app.C1pFEditField.Value*1e-12);
            % app.Zl1 = 1i*2*pi*app.F1*1e6*app.L1nHEditField.Value*1e-9;
            % app.Zc2 = 1./(1i*2*pi*app.F1*1e6*app.C2pFEditField.Value*1e-12);
            
            % Re-calcualte the LCC reactance
            if strcmp(app.DoubleTunedNetworkButtonGroup.SelectedObject.Text,'LCC Type 1')
                app.LCC_React = app.Zc1 + 1./( (1./app.Zl1) + (1./app.Zc2) );
            elseif strcmp(app.DoubleTunedNetworkButtonGroup.SelectedObject.Text,'LCC Type 2')
                app.LCC_React = 1./( (1./(app.Zc1 + app.Zl1)) + (1./app.Zc2) );
            end

                    % LF_17O
                    % LF_2H
                    % LF_13C
                    % LF_23Na
                    % LF_129Xe
                    % LF_31P
                    % LF_3He
                    % LF_19F
                    % LF_1H

            app.XL_Delta_17O = imag(app.Z1(app.ind17O)+app.LCC_React(app.ind17O)); 
            app.XL_Delta_2H = imag(app.Z1(app.ind2H)+app.LCC_React(app.ind2H)); 
            app.XL_Delta_13C = imag(app.Z1(app.ind13C)+app.LCC_React(app.ind13C)); 
            app.XL_Delta_23Na = imag(app.Z1(app.ind23Na)+app.LCC_React(app.ind23Na)); 
            app.XL_Delta_129Xe = imag(app.Z1(app.ind129Xe)+app.LCC_React(app.ind129Xe)); 
            app.XL_Delta_31P = imag(app.Z1(app.ind31P)+app.LCC_React(app.ind31P)); 
            app.XL_Delta_3He = imag(app.Z1(app.ind3He)+app.LCC_React(app.ind3He)); 
            app.XL_Delta_19F = imag(app.Z1(app.ind19F)+app.LCC_React(app.ind19F)); 
            app.XL_Delta_1H = imag(app.Z1(app.ind1H)+app.LCC_React(app.ind1H)); 

            hold(app.UIAxes,'on');
            set(app.R2, 'YData', imag(app.LCC_React))
            drawnow

            set(app.TD17O, 'String', {['△XL ' '(' num2str(round(app.XL_Delta_17O,1)) 'j)'] ,...
                ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind17O)),2)) 'ohm'] });

            set(app.TD2H, 'String', {['△XL ' '(' num2str(round(app.XL_Delta_2H,1)) 'j)'] ,...
                ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind2H)),2)) 'ohm'] });

            set(app.TD13C, 'String', {['△XL ' '(' num2str(round(app.XL_Delta_13C,1)) 'j)'] ,...
                ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind13C)),2)) 'ohm'] });

            set(app.TD23Na, 'String', {['△XL ' '(' num2str(round(app.XL_Delta_23Na,1)) 'j)'] ,...
                ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind23Na)),2)) 'ohm'] });

            set(app.TD129Xe, 'String', {['△XL ' '(' num2str(round(app.XL_Delta_129Xe,1)) 'j)'] ,...
                ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind129Xe)),2)) 'ohm'] });

            set(app.TD31P, 'String', {['△XL ' '(' num2str(round(app.XL_Delta_31P,1)) 'j)'] ,...
                ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind31P)),2)) 'ohm'] });

            set(app.TD3He, 'String', {['△XL ' '(' num2str(round(app.XL_Delta_3He,1)) 'j)'] ,...
                ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind3He)),2)) 'ohm'] });

            set(app.TD19F, 'String', {['△XL ' '(' num2str(round(app.XL_Delta_19F,1)) 'j)'] ,...
                ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind19F)),2)) 'ohm'] });

            set(app.TD1H, 'String', {['△XL ' '(' num2str(round(app.XL_Delta_1H,1)) 'j)'] ,...
                ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind1H)),2)) 'ohm'] });


% app.UIAxes,app.F1(app.ind13C),imag(app.LCC_React(app.ind13C))

            set(app.MD17O, 'XData', app.F1(app.ind17O));
            set(app.MD17O, 'YData', imag(app.LCC_React(app.ind17O)));

            set(app.MD2H, 'XData', app.F1(app.ind2H));
            set(app.MD2H, 'YData', imag(app.LCC_React(app.ind2H)));

            set(app.MD13C, 'XData', app.F1(app.ind13C));
            set(app.MD13C, 'YData', imag(app.LCC_React(app.ind13C)));

            set(app.MD23Na, 'XData', app.F1(app.ind23Na));
            set(app.MD23Na, 'YData', imag(app.LCC_React(app.ind23Na)));

            set(app.MD129Xe, 'XData', app.F1(app.ind129Xe));
            set(app.MD129Xe, 'YData', imag(app.LCC_React(app.ind129Xe)));

            set(app.MD31P, 'XData', app.F1(app.ind31P));
            set(app.MD31P, 'YData', imag(app.LCC_React(app.ind31P)));

            set(app.MD3He, 'XData', app.F1(app.ind3He));
            set(app.MD3He, 'YData', imag(app.LCC_React(app.ind3He)));

            set(app.MD19F, 'XData', app.F1(app.ind19F));
            set(app.MD19F, 'YData', imag(app.LCC_React(app.ind19F)));

            set(app.MD1H, 'XData', app.F1(app.ind1H));
            set(app.MD1H, 'YData', imag(app.LCC_React(app.ind1H)));


        end

        
        function [Zc] = Zcalc_Cap(app,CpF,Rohm)
            Zc = Rohm + 1./(1i*2*pi*app.F1*1e6*CpF*1e-12);
        end
        
        function [Zl] = Zcalc_Ind(app,LnH,Rohm)
            Zl = Rohm+1i*2*pi*app.F1*1e6*LnH*1e-9;
        end
        
        function [] = PlotSourceReactance(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % source reactance first plot here
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.max1 = max(abs(app.Z1)); app.min1 = min(abs(app.Z1)); 
            app.YaxisLimitEditField.Value = app.max1*2;
            app.YaxisLimitSlider.Limits = [0,app.max1*2*2];
            app.YaxisLimitSlider.Value = app.max1*2;

            app.R1 = plot(app.UIAxes,app.F1,-imag(app.Z1));

            legend(app.UIAxes,app.R1,'(-) Source Reactance')
            title(app.UIAxes,'(-) Sourece Reactance vs LCC Network Reactance')
            xlabel(app.UIAxes,'Frequency Range in MHz')
            ylabel(app.UIAxes,'Reactance')
            axis(app.UIAxes,'tight')
            ylim(app.UIAxes,[-app.max1*2 app.max1*2])
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            X_offset = app.X_off; Y_offset = -app.Y_off;

            % ErrLimit = 0.5;

            hold(app.UIAxes,'on')
            if app.Mark17OCheckBox.Value == 1 %17O
                [Err,app.ind17O] = min(abs(app.F1-app.LF_17O));
                if Err>=app.ErrLimit
                    error('Error in Marking Nucleus Frequency')
                end
                app.M17O = plot(app.UIAxes,app.F1(app.ind17O),-imag(app.Z1(app.ind17O)),'x','MarkerSize', 16,'Color',app.Color17OColorPicker.Value);
                text(app.UIAxes,app.F1(app.ind17O)*X_offset,-imag(app.Z1(app.ind17O))+Y_offset,['17O (' num2str(round(app.LF_17O,1)) 'MHz)' ],...
                    'Color',app.Color17OColorPicker.Value)
                set(get(get(app.M17O,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end
            if app.Mark2HCheckBox.Value == 1 %2H
                [Err,app.ind2H] = min(abs(app.F1-app.LF_2H));
                if Err>=app.ErrLimit
                    error('Error in Marking Nucleus Frequency')
                end
                app.M2H = plot(app.UIAxes,app.F1(app.ind2H),-imag(app.Z1(app.ind2H)),'x','MarkerSize', 16,'Color',app.Color2HColorPicker.Value);
                text(app.UIAxes,app.F1(app.ind2H)*X_offset,-imag(app.Z1(app.ind2H))+Y_offset,['2H (' num2str(round(app.LF_2H,1)) 'MHz)' ],...
                    'Color',app.Color2HColorPicker.Value)
                set(get(get(app.M2H,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end
            if app.Mark13CCheckBox.Value == 1 %13C
                [Err,app.ind13C] = min(abs(app.F1-app.LF_13C));
                if Err>=app.ErrLimit
                    error('Error in Marking Nucleus Frequency')
                end
                app.M13C = plot(app.UIAxes,app.F1(app.ind13C),-imag(app.Z1(app.ind13C)),'x','MarkerSize', 16,'Color',app.Color13CColorPicker.Value);
                text(app.UIAxes,app.F1(app.ind13C)*X_offset,-imag(app.Z1(app.ind13C))+Y_offset,['13C (' num2str(round(app.LF_13C,1)) 'MHz)' ],...
                    'Color',app.Color13CColorPicker.Value)
                set(get(get(app.M13C,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end
            if app.Mark23NaCheckBox.Value == 1 %23Na
                [Err,app.ind23Na] = min(abs(app.F1-app.LF_23Na));
                if Err>=app.ErrLimit
                    error('Error in Marking Nucleus Frequency')
                end
                app.M23Na = plot(app.UIAxes,app.F1(app.ind23Na),-imag(app.Z1(app.ind23Na)),'x','MarkerSize', 16,'Color',app.Color23NaColorPicker.Value);
                text(app.UIAxes,app.F1(app.ind23Na)*X_offset,-imag(app.Z1(app.ind23Na))+Y_offset,['23Na (' num2str(round(app.LF_23Na,1)) 'MHz)' ],...
                    'Color',app.Color23NaColorPicker.Value)
                set(get(get(app.M23Na,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end
            if app.Mark129XeCheckBox.Value == 1 %129Xe
                [Err,app.ind129Xe] = min(abs(app.F1-app.LF_129Xe));
                if Err>=app.ErrLimit
                    error('Error in Marking Nucleus Frequency')
                end
                app.M129Xe = plot(app.UIAxes,app.F1(app.ind129Xe),-imag(app.Z1(app.ind129Xe)),'x','MarkerSize', 16,'Color',app.Color129XeColorPicker.Value);
                text(app.UIAxes,app.F1(app.ind129Xe)*X_offset,-imag(app.Z1(app.ind129Xe))+Y_offset,['129Xe (' num2str(round(app.LF_129Xe,1)) 'MHz)' ],...
                    'Color',app.Color129XeColorPicker.Value)
                set(get(get(app.M129Xe,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end
            if app.Mark31PCheckBox.Value == 1 %31P
                [Err,app.ind31P] = min(abs(app.F1-app.LF_31P));
                if Err>=app.ErrLimit
                    error('Error in Marking Nucleus Frequency')
                end
                app.M31P = plot(app.UIAxes,app.F1(app.ind31P),-imag(app.Z1(app.ind31P)),'x','MarkerSize', 16,'Color',app.Color31PColorPicker.Value);
                text(app.UIAxes,app.F1(app.ind31P)*X_offset,-imag(app.Z1(app.ind31P))+Y_offset,['31P (' num2str(round(app.LF_31P,1)) 'MHz)' ],...
                    'Color',app.Color31PColorPicker.Value)
                set(get(get(app.M31P,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end
            if app.Mark3HeCheckBox.Value == 1 %3He
                [Err,app.ind3He] = min(abs(app.F1-app.LF_3He));
                if Err>=app.ErrLimit
                    error('Error in Marking Nucleus Frequency')
                end
                app.M3He = plot(app.UIAxes,app.F1(app.ind3He),-imag(app.Z1(app.ind3He)),'x','MarkerSize', 16,'Color',app.Color3HeColorPicker.Value);
                text(app.UIAxes,app.F1(app.ind3He)*X_offset,-imag(app.Z1(app.ind3He))+Y_offset,['3He (' num2str(round(app.LF_3He,1)) 'MHz)' ],...
                    'Color',app.Color3HeColorPicker.Value)
                set(get(get(app.M3He,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end
            if app.Mark19FCheckBox.Value == 1 %19F
                [Err,app.ind19F] = min(abs(app.F1-app.LF_19F));
                if Err>=app.ErrLimit
                    error('Error in Marking Nucleus Frequency')
                end
                app.M19F=plot(app.UIAxes,app.F1(app.ind19F),-imag(app.Z1(app.ind19F)),'x','MarkerSize', 16,'Color',app.Color19FColorPicker.Value);
                text(app.UIAxes,app.F1(app.ind19F)*X_offset,-imag(app.Z1(app.ind19F))+Y_offset,['19F (' num2str(round(app.LF_19F,1)) 'MHz)' ],...
                    'Color',app.Color19FColorPicker.Value)
                set(get(get(app.M19F,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end
            if app.Mark1HCheckBox.Value == 1
                [Err,app.ind1H] = min(abs(app.F1-app.LF_1H));
                if Err>=app.ErrLimit
                    error('Error in Marking Nucleus Frequency')
                end
                app.M1H=plot(app.UIAxes,app.F1(app.ind1H),-imag(app.Z1(app.ind1H)),'x','MarkerSize', 16,'Color',app.Color1HColorPicker.Value);
                text(app.UIAxes,app.F1(app.ind1H)*X_offset,-imag(app.Z1(app.ind1H))+Y_offset,['1H (' num2str(round(app.LF_1H,1)) 'MHz)' ],...
                    'Color',app.Color1HColorPicker.Value);
                set(get(get(app.M1H,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end
            hold(app.UIAxes,'off')
        end
        
        function [] = UpdateDiffMarkerInfo(app)

            % X_offset2 = 0.95; Y_offset2 = +50;
            % 
            % if app.Mark2HCheckBox.Value == 1 %2H
            %     [Err,ind2H] = min(abs(app.F1-app.LF_2H));
            %     if Err>=app.ErrLimit
            %         error('Error in Marking Nucleus Frequency')
            %     end
            %     XL_Delta_2H = imag(app.Z1(ind2H)+app.LCC_React(ind2H)); 
            %     MD2H = plot(app.UIAxes,app.F1(ind2H),-imag(app.Z1(ind2H)),'.','MarkerSize', 16,'Color',app.Color2HColorPicker.Value);
            %     text(app.UIAxes,app.F1(ind2H)*X_offset2,-imag(app.Z1(ind2H))+Y_offset2,['△XL ' '(' num2str(round(XL_Delta_2H,1)) 'j)' ],...
            %         'Color',app.Color2HColorPicker.Value)
            %     set(get(get(MD2H,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            % end
            
            
        end
        
        function [] = UpdateFreqIndex(app)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [Err,app.ind17O] = min(abs(app.F1-app.LF_17O));
            % if Err>=app.ErrLimit
            %     error('Error in Marking Nucleus Frequency')
            % end
            [Err,app.ind2H] = min(abs(app.F1-app.LF_2H));
            % if Err>=app.ErrLimit
            %     error('Error in Marking Nucleus Frequency')
            % end
            [Err,app.ind13C] = min(abs(app.F1-app.LF_13C));
            % if Err>=app.ErrLimit
            %     error('Error in Marking Nucleus Frequency')
            % end
            [Err,app.ind23Na] = min(abs(app.F1-app.LF_23Na));
            % if Err>=app.ErrLimit
            %     error('Error in Marking Nucleus Frequency')
            % end
            [Err,app.ind129Xe] = min(abs(app.F1-app.LF_129Xe));
            % if Err>=app.ErrLimit
            %     error('Error in Marking Nucleus Frequency')
            % end
            [Err,app.ind31P] = min(abs(app.F1-app.LF_31P));
            % if Err>=app.ErrLimit
            %     error('Error in Marking Nucleus Frequency')
            % end
            [Err,app.ind3He] = min(abs(app.F1-app.LF_3He));
            % if Err>=app.ErrLimit
            %     error('Error in Marking Nucleus Frequency')
            % end
            [Err,app.ind19F] = min(abs(app.F1-app.LF_19F));
            % if Err>=app.ErrLimit
            %     error('Error in Marking Nucleus Frequency')
            % end
            [Err,app.ind1H] = min(abs(app.F1-app.LF_1H));
            % if Err>=app.ErrLimit
            %     error('Error in Marking Nucleus Frequency')
            % end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FieldStrengthTEditField
        function FieldStrengthTEditFieldValueChanged(app, event)
            app.FieldStrength = app.FieldStrengthTEditField.Value;

            app.LF_17O = app.FieldStrength*5.772;
            app.OMHzEditField.Value = app.LF_17O;

            app.LF_2H = app.FieldStrength*6.536;
            app.HMHzEditField_2.Value = app.LF_2H;

            app.LF_13C = app.FieldStrength*10.7084;
            app.CMHzEditField.Value = app.LF_13C;

            app.LF_23Na = app.FieldStrength*11.262;
            app.NaMHzEditField.Value = app.LF_23Na;

            app.LF_129Xe = app.FieldStrength*11.7767338;
            app.XeMHzEditField.Value = app.LF_129Xe;

            app.LF_31P = app.FieldStrength*17.235;
            app.PMHzEditField.Value = app.LF_31P;

            app.LF_3He = app.FieldStrength*32.434100033;
            app.HeMHzEditField.Value = app.LF_3He;

            app.LF_19F = app.FieldStrength*40.078;
            app.FMHzEditField.Value = app.LF_19F;

            app.LF_1H = app.FieldStrength*42.577478461;
            app.HMHzEditField.Value = app.LF_1H;
            
        end

        % Button pushed function: PlotSourceImpedanceButton
        function PlotSourceImpedanceButtonPushed(app, event)
            
            app.Freq_S = app.FrequencyMHzStartEditField.Value; 
            app.Freq_E = app.FrequencyMHzEndEditField.Value; 
            app.Freq_NofS = app.ofStepsEditField.Value; 
            app.IndCap = app.SourceInductanceuHorCapacitancepFEditField.Value;


            app.F1 = linspace(app.Freq_S, app.Freq_E, app.Freq_NofS); % in MHz

            if strcmp(app.SourceTypeDropDown.Value,'Coil (Inductance)')
                app.Z1 = 1i*2*pi*app.F1*1e6*app.IndCap*1e-6;
            elseif strcmp(app.SourceTypeDropDown.Value,'Trap (Capacitance)')
                app.Z1 = 1./(1i*2*pi*app.F1*1e6*app.IndCap*1e-12);
            end

            UpdateFreqIndex(app)

            PlotSourceReactance(app)

        end

        % Button pushed function: ClickheretoInitiateOrResetButton
        function ClickheretoInitiateOrResetButtonPushed(app, event)
            
            clc 

            app.Z1 = [];
            app.F1 = [];

            cla(app.UIAxes ,'reset')
            
            title(app.UIAxes,'(-) Sourece Reactance vs LCC Network Reactance')
            xlabel(app.UIAxes,'Frequency Range in MHz')
            ylabel(app.UIAxes,'Reactance')

            app.ErrLimit = 0.5;

            app.FieldStrength = app.FieldStrengthTEditField.Value;

            app.LF_17O = app.FieldStrength*5.772;
            app.OMHzEditField.Value = app.LF_17O;

            app.LF_2H = app.FieldStrength*6.536;
            app.HMHzEditField_2.Value = app.LF_2H;

            app.LF_13C = app.FieldStrength*10.7084;
            app.CMHzEditField.Value = app.LF_13C;

            app.LF_23Na = app.FieldStrength*11.262;
            app.NaMHzEditField.Value = app.LF_23Na;

            app.LF_129Xe = app.FieldStrength*11.7767338;
            app.XeMHzEditField.Value = app.LF_129Xe;

            app.LF_31P = app.FieldStrength*17.235;
            app.PMHzEditField.Value = app.LF_31P;

            app.LF_3He = app.FieldStrength*32.434100033;
            app.HeMHzEditField.Value = app.LF_3He;

            app.LF_19F = app.FieldStrength*40.078;
            app.FMHzEditField.Value = app.LF_19F;

            app.LF_1H = app.FieldStrength*42.577478461;
            app.HMHzEditField.Value = app.LF_1H;

            
        end

        % Button pushed function: ClickHeretoPlotLCCNetworkImpedanceButton
        function ClickHeretoPlotLCCNetworkImpedanceButtonPushed(app, event)
    
            app.Zc1 = Zcalc_Cap(app,app.C1pFEditField.Value,app.C1lossOhmEditField.Value);
            app.Zl1 = Zcalc_Ind(app,app.L1nHEditField.Value,app.L1lossOhmEditField.Value);
            app.Zc2 = Zcalc_Cap(app,app.C2pFEditField.Value,app.C2lossOhmEditField.Value);
            
            Update_LCC_React(app)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % LCC reactance first plot here
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            hold(app.UIAxes,'on')
            app.R2 = plot(app.UIAxes,app.F1,imag(app.LCC_React));
            legend(app.UIAxes,[app.R1 app.R2],'(-) Source Reactance','LCC Reactance')
            % hold(app.UIAxes,'off')
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            % UpdateMarkerInfo(app)
                    % LF_17O
                    % LF_2H
                    % LF_13C
                    % LF_23Na
                    % LF_129Xe
                    % LF_31P
                    % LF_3He
                    % LF_19F
                    % LF_1H
            X_offset2 = app.X_off; Y_offset2 = +app.Y_off;

            if app.Mark17OCheckBox.Value == 1 %17O
                app.XL_Delta_17O = imag(app.Z1(app.ind17O)+app.LCC_React(app.ind17O)); 

                app.MD17O = plot(app.UIAxes,app.F1(app.ind17O),imag(app.LCC_React(app.ind17O)),'.','MarkerSize', 16,'Color',app.Color17OColorPicker.Value);
                app.TD17O = text(app.UIAxes,app.F1(app.ind17O)*X_offset2,-imag(app.Z1(app.ind17O))+Y_offset2,...
                    {['△XL ' '(' num2str(round(app.XL_Delta_17O,1)) 'j)'] , ...
                    ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind17O)),2)) 'ohm'] },...
                    'Color',app.Color17OColorPicker.Value);
                set(get(get(app.MD17O,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end

            if app.Mark2HCheckBox.Value == 1 %2H
                app.XL_Delta_2H = imag(app.Z1(app.ind2H)+app.LCC_React(app.ind2H)); 

                app.MD2H = plot(app.UIAxes,app.F1(app.ind2H),imag(app.LCC_React(app.ind2H)),'.','MarkerSize', 16,'Color',app.Color2HColorPicker.Value);
                app.TD2H = text(app.UIAxes,app.F1(app.ind2H)*X_offset2,-imag(app.Z1(app.ind2H))+Y_offset2,...
                    {['△XL ' '(' num2str(round(app.XL_Delta_2H,1)) 'j)'] , ...
                    ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind2H)),2)) 'ohm'] },...
                    'Color',app.Color2HColorPicker.Value);
                set(get(get(app.MD2H,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end

            if app.Mark13CCheckBox.Value == 1 %13C
                app.XL_Delta_13C = imag(app.Z1(app.ind13C)+app.LCC_React(app.ind13C)); 

                app.MD13C = plot(app.UIAxes,app.F1(app.ind13C),imag(app.LCC_React(app.ind13C)),'.','MarkerSize', 16,'Color',app.Color13CColorPicker.Value);
                app.TD13C = text(app.UIAxes,app.F1(app.ind13C)*X_offset2,-imag(app.Z1(app.ind13C))+Y_offset2,...
                    {['△XL ' '(' num2str(round(app.XL_Delta_13C,1)) 'j)'] , ...
                    ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind13C)),2)) 'ohm'] },...
                    'Color',app.Color13CColorPicker.Value);
                set(get(get(app.MD13C,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end

            if app.Mark23NaCheckBox.Value == 1 %23Na
                app.XL_Delta_23Na = imag(app.Z1(app.ind23Na)+app.LCC_React(app.ind23Na)); 

                app.MD23Na = plot(app.UIAxes,app.F1(app.ind23Na),imag(app.LCC_React(app.ind23Na)),'.','MarkerSize', 16,'Color',app.Color23NaColorPicker.Value);
                app.TD23Na = text(app.UIAxes,app.F1(app.ind23Na)*X_offset2,-imag(app.Z1(app.ind23Na))+Y_offset2,...
                    {['△XL ' '(' num2str(round(app.XL_Delta_23Na,1)) 'j)'] , ...
                    ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind23Na)),2)) 'ohm'] },...
                    'Color',app.Color23NaColorPicker.Value);
                set(get(get(app.MD23Na,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end

            if app.Mark129XeCheckBox.Value == 1 %129Xe
                app.XL_Delta_129Xe = imag(app.Z1(app.ind129Xe)+app.LCC_React(app.ind129Xe)); 

                app.MD129Xe = plot(app.UIAxes,app.F1(app.ind129Xe),imag(app.LCC_React(app.ind129Xe)),'.','MarkerSize', 16,'Color',app.Color129XeColorPicker.Value);
                app.TD129Xe = text(app.UIAxes,app.F1(app.ind129Xe)*X_offset2,-imag(app.Z1(app.ind129Xe))+Y_offset2,...
                    {['△XL ' '(' num2str(round(app.XL_Delta_129Xe,1)) 'j)'] , ...
                    ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind129Xe)),2)) 'ohm'] },...
                    'Color',app.Color129XeColorPicker.Value);
                set(get(get(app.MD129Xe,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end

            if app.Mark31PCheckBox.Value == 1 %31P
                app.XL_Delta_31P = imag(app.Z1(app.ind31P)+app.LCC_React(app.ind31P)); 

                app.MD31P = plot(app.UIAxes,app.F1(app.ind31P),imag(app.LCC_React(app.ind31P)),'.','MarkerSize', 16,'Color',app.Color31PColorPicker.Value);
                app.TD31P = text(app.UIAxes,app.F1(app.ind31P)*X_offset2,-imag(app.Z1(app.ind31P))+Y_offset2,...
                    {['△XL ' '(' num2str(round(app.XL_Delta_31P,1)) 'j)'] , ...
                    ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind31P)),2)) 'ohm'] },...
                    'Color',app.Color31PColorPicker.Value);
                set(get(get(app.MD31P,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end

            if app.Mark3HeCheckBox.Value == 1 %3He
                app.XL_Delta_3He = imag(app.Z1(app.ind3He)+app.LCC_React(app.ind3He)); 

                app.MD3He = plot(app.UIAxes,app.F1(app.ind3He),imag(app.LCC_React(app.ind3He)),'.','MarkerSize', 16,'Color',app.Color3HeColorPicker.Value);
                app.TD3He = text(app.UIAxes,app.F1(app.ind3He)*X_offset2,-imag(app.Z1(app.ind3He))+Y_offset2,...
                    {['△XL ' '(' num2str(round(app.XL_Delta_3He,1)) 'j)'] , ...
                    ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind3He)),2)) 'ohm'] },...
                    'Color',app.Color3HeColorPicker.Value);
                set(get(get(app.MD3He,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end

            if app.Mark19FCheckBox.Value == 1 %19F
                app.XL_Delta_19F = imag(app.Z1(app.ind19F)+app.LCC_React(app.ind19F)); 

                app.MD19F = plot(app.UIAxes,app.F1(app.ind19F),imag(app.LCC_React(app.ind19F)),'.','MarkerSize', 16,'Color',app.Color19FColorPicker.Value);
                app.TD19F = text(app.UIAxes,app.F1(app.ind19F)*X_offset2,-imag(app.Z1(app.ind19F))+Y_offset2,...
                    {['△XL ' '(' num2str(round(app.XL_Delta_19F,1)) 'j)'] , ...
                    ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind19F)),2)) 'ohm'] },...
                    'Color',app.Color19FColorPicker.Value);
                set(get(get(app.MD19F,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end

            if app.Mark1HCheckBox.Value == 1 %1H
                app.XL_Delta_1H = imag(app.Z1(app.ind1H)+app.LCC_React(app.ind1H)); 

                app.MD1H = plot(app.UIAxes,app.F1(app.ind1H),imag(app.LCC_React(app.ind1H)),'.','MarkerSize', 16,'Color',app.Color1HColorPicker.Value);
                app.TD1H = text(app.UIAxes,app.F1(app.ind1H)*X_offset2,-imag(app.Z1(app.ind1H))+Y_offset2,...
                    {['△XL ' '(' num2str(round(app.XL_Delta_1H,1)) 'j)'] , ...
                    ['Eq. Loss ' num2str(round(real(app.LCC_React(app.ind1H)),2)) 'ohm'] },...
                    'Color',app.Color1HColorPicker.Value);
                set(get(get(app.MD1H,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
            end


        end

        % Value changed function: C1pFEditField
        function C1pFEditFieldValueChanged(app, event)
            app.Zc1 = Zcalc_Cap(app,app.C1pFEditField.Value,app.C1lossOhmEditField.Value);
            Update_LCC_React(app)
            % hold(app.UIAxes,'on');
            % set(app.R2, 'YData', imag(app.LCC_React))
            % drawnow
            app.C1pFSlider.Value = app.C1pFEditField.Value;
        end

        % Value changed function: L1nHEditField
        function L1nHEditFieldValueChanged(app, event)
            app.Zl1 = Zcalc_Ind(app,app.L1nHEditField.Value,app.L1lossOhmEditField.Value);
            Update_LCC_React(app)
            % hold(app.UIAxes,'on')
            % set(app.R2, 'YData', imag(app.LCC_React))
            % drawnow
            app.L1nHSlider.Value = app.L1nHEditField.Value;
        end

        % Value changed function: C2pFEditField
        function C2pFEditFieldValueChanged(app, event)
            app.Zc2 = Zcalc_Cap(app,app.C2pFEditField.Value,app.C2lossOhmEditField.Value);
            Update_LCC_React(app)
            % hold(app.UIAxes,'on')
            % set(app.R2, 'YData', imag(app.LCC_React))
            % drawnow
            app.C2pFSlider.Value = app.C2pFEditField.Value;
        end

        % Value changing function: C1pFSlider
        function C1pFSliderValueChanging(app, event)
            changingValue = event.Value;
            app.C1pFEditField.Value = changingValue;

            app.Zc1 = Zcalc_Cap(app,changingValue,app.C1lossOhmEditField.Value);
            Update_LCC_React(app)
            % hold(app.UIAxes,'on')
            % set(app.R2, 'YData', imag(app.LCC_React))
            % drawnow           

            % UpdateDiffMarkerInfo(app)
            % set(app.TD2H, 'String', ['△XL ' '(' num2str(round(app.XL_Delta_2H,1)) 'j)' ])
        end

        % Value changing function: L1nHSlider
        function L1nHSliderValueChanging(app, event)
            changingValue = event.Value;
            app.L1nHEditField.Value = changingValue;

            app.Zl1 = Zcalc_Ind(app,changingValue,app.L1lossOhmEditField.Value);
            Update_LCC_React(app)
            % hold(app.UIAxes,'on')
            % set(app.R2, 'YData', imag(app.LCC_React))
            % drawnow
            
        end

        % Value changing function: C2pFSlider
        function C2pFSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Zc2 = Zcalc_Cap(app,changingValue,app.C2lossOhmEditField.Value);

            app.C2pFEditField.Value = changingValue;
            Update_LCC_React(app)
            % hold(app.UIAxes,'on')
            % set(app.R2, 'YData', imag(app.LCC_React))
            % drawnow
        end

        % Button pushed function: ReCenterC1Button
        function ReCenterC1ButtonPushed(app, event)
            app.C1pFSlider.Limits = [app.C1pFEditField.Value*0.5,app.C1pFEditField.Value*1.5];
        end

        % Button pushed function: ReCenterL1Button
        function ReCenterL1ButtonPushed(app, event)
            app.L1nHSlider.Limits = [app.L1nHEditField.Value*0.5,app.L1nHEditField.Value*1.5];
        end

        % Button pushed function: ReCenterC2Button
        function ReCenterC2ButtonPushed(app, event)
            app.C2pFSlider.Limits = [app.C2pFEditField.Value*0.5,app.C2pFEditField.Value*1.5];
        end

        % Button pushed function: PlotMeasuredSourceImpedanceButton
        function PlotMeasuredSourceImpedanceButtonPushed(app, event)
            T = readtable(app.VNACoilFileNameEditField.Value, 'HeaderLines',1);
            T = T{:,:};
            app.F1 = T(:,1)*1e-6;
            Gammas_Z(:,1) = T(:,2) .* exp(1i*T(:,3)*pi/180) ;
            app.Z1(:,1) =  gamma2z(Gammas_Z(:,1)) ;

            UpdateFreqIndex(app)

            PlotSourceReactance(app)

        end

        % Value changed function: YaxisLimitEditField
        function YaxisLimitEditFieldValueChanged(app, event)
            value = app.YaxisLimitEditField.Value;
            app.YaxisLimitSlider.Limits = [0,value*2];
            app.YaxisLimitSlider.Value = value; 
            
            ylim(app.UIAxes,[-value value])
        end

        % Value changing function: YaxisLimitSlider
        function YaxisLimitSliderValueChanging(app, event)
            changingValue = event.Value;
            app.YaxisLimitEditField.Value = changingValue;
            
            ylim(app.UIAxes,[-changingValue changingValue])
        end

        % Button pushed function: ReCenterYlimitButton
        function ReCenterYlimitButtonPushed(app, event)
            app.YaxisLimitSlider.Limits = [0,app.YaxisLimitSlider.Value*2];
        end

        % Value changed function: C1lossOhmEditField
        function C1lossOhmEditFieldValueChanged(app, event)
            % value = app.C1lossOhmEditField.Value;
            app.Zc1 = Zcalc_Cap(app,app.C1pFEditField.Value,app.C1lossOhmEditField.Value);
            Update_LCC_React(app)
        end

        % Value changed function: L1lossOhmEditField
        function L1lossOhmEditFieldValueChanged(app, event)
            app.Zl1 = Zcalc_Ind(app,app.L1nHEditField.Value,app.L1lossOhmEditField.Value);
            Update_LCC_React(app)
            
        end

        % Value changed function: C2lossOhmEditField
        function C2lossOhmEditFieldValueChanged(app, event)
            % value = app.C2lossOhmEditField.Value;
            app.Zc2 = Zcalc_Cap(app,app.C2pFEditField.Value,app.C2lossOhmEditField.Value);
            Update_LCC_React(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '(-) Sourece Reactance vs LCC Network Reactance')
            xlabel(app.UIAxes, 'Frequency Range in MHz')
            ylabel(app.UIAxes, 'Reactance')
            zlabel(app.UIAxes, 'Z')
            app.DoubleTunedCircuitDesignHelperforMRRFCoilTrapBalunLabel = uilabel(app.UIFigure);
            app.DoubleTunedCircuitDesignHelperforMRRFCoilTrapBalunLabel.Text = {'Double-Tuned Circuit Design Helper'; 'for MR RF Coil/Trap/Balun'};
            app.ClickheretoInitiateOrResetButton = uibutton(app.UIFigure, 'push');
            app.ClickheretoInitiateOrResetButton.ButtonPushedFcn = createCallbackFcn(app, @ClickheretoInitiateOrResetButtonPushed, true);
            app.ClickheretoInitiateOrResetButton.Text = 'Click here to Initiate Or Reset';
            app.YaxisLimitEditFieldLabel = uilabel(app.UIFigure);
            app.YaxisLimitEditFieldLabel.Text = 'Yaxis Limit';
            app.YaxisLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.YaxisLimitEditField.ValueChangedFcn = createCallbackFcn(app, @YaxisLimitEditFieldValueChanged, true);
            app.YaxisLimitSliderLabel = uilabel(app.UIFigure);
            app.YaxisLimitSliderLabel.Text = 'Yaxis Limit';
            app.YaxisLimitSlider = uislider(app.UIFigure);
            app.YaxisLimitSlider.ValueChangingFcn = createCallbackFcn(app, @YaxisLimitSliderValueChanging, true);
            app.Step1LarmorFrequencyTablePanel = uipanel(app.UIFigure);
            app.Step1LarmorFrequencyTablePanel.Title = 'Step1. Larmor Frequency Table';
            app.Mark17OCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark17OCheckBox.Text = 'Mark 17O';
            app.Mark1HCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark1HCheckBox.Text = 'Mark 1H';
            app.Mark31PCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark31PCheckBox.Text = 'Mark 31P';
            app.Mark2HCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark2HCheckBox.Text = 'Mark 2H';
            app.Mark13CCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark13CCheckBox.Text = 'Mark 13C';
            app.Mark23NaCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark23NaCheckBox.Text = 'Mark 23Na';
            app.Mark129XeCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark129XeCheckBox.Text = 'Mark 129Xe';
            app.Mark3HeCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark3HeCheckBox.Text = 'Mark 3He';
            app.Mark19FCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark19FCheckBox.Text = 'Mark 19F';
            app.OMHzLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.OMHzLabel.Text = '17O (MHz)';
            app.OMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.OMHzEditField.Value = 27.1284;
            app.CMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.CMHzEditFieldLabel.Text = '13C (MHz)';
            app.CMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.CMHzEditField.Value = 50.32948;
            app.NaMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.NaMHzEditFieldLabel.Text = '23Na (MHz)';
            app.NaMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.NaMHzEditField.Value = 52.9314;
            app.XeMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.XeMHzEditFieldLabel.Text = '129Xe (MHz)';
            app.XeMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.XeMHzEditField.Value = 55.35064886;
            app.PMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.PMHzEditFieldLabel.Text = '31P (MHz)';
            app.PMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.PMHzEditField.Value = 81.0045;
            app.HeMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.HeMHzEditFieldLabel.Text = '3He (MHz)';
            app.HeMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.HeMHzEditField.Value = 152.4402701551;
            app.FMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.FMHzEditFieldLabel.Text = '19F (MHz)';
            app.FMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.FMHzEditField.Value = 188.3666;
            app.HMHzEditField_2Label = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.HMHzEditField_2Label.Text = '2H (MHz)';
            app.HMHzEditField_2 = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.HMHzEditField_2.Value = 30.7192;
            app.HMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.HMHzEditFieldLabel.Text = '1H (MHz)';
            app.HMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.HMHzEditField.Value = 200.1141487667;
            app.Color17OColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color17OColorPickerLabel.Text = 'Color 17O  ';
            app.Color17OColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color17OColorPicker.Value = [1 0 1];
            app.Color1HColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color1HColorPickerLabel.Text = 'Color 1H';
            app.Color1HColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color2HColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color2HColorPickerLabel.Text = 'Color 2H';
            app.Color2HColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color2HColorPicker.Value = [0.52156862745098 0.0862745098039216 0.819607843137255];
            app.Color13CColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color13CColorPickerLabel.Text = 'Color 13C';
            app.Color13CColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color13CColorPicker.Value = [0.650980392156863 0.650980392156863 0.650980392156863];
            app.Color23NaColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color23NaColorPickerLabel.Text = 'Color 23Na';
            app.Color23NaColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color23NaColorPicker.Value = [0.96078431372549 0.466666666666667 0.16078431372549];
            app.Color129XeColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color129XeColorPickerLabel.Text = 'Color 129Xe';
            app.Color129XeColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color129XeColorPicker.Value = [1 1 0];
            app.Color3HeColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color3HeColorPickerLabel.Text = 'Color 3He';
            app.Color3HeColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color3HeColorPicker.Value = [0.286274509803922 0.858823529411765 0.250980392156863];
            app.Color19FColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color19FColorPickerLabel.Text = 'Color 19F';
            app.Color19FColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color19FColorPicker.Value = [0.423529411764706 0.956862745098039 1];
            app.Color31PColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color31PColorPickerLabel.Text = 'Color 31P';
            app.Color31PColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color31PColorPicker.Value = [0 0 1];
            app.FieldStrengthTEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.FieldStrengthTEditFieldLabel.Text = 'Field Strength (T)';
            app.FieldStrengthTEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.FieldStrengthTEditField.ValueChangedFcn = createCallbackFcn(app, @FieldStrengthTEditFieldValueChanged, true);
            app.FieldStrengthTEditField.Value = 4.7;
            app.Step2CreateSourceImpedancePanel = uipanel(app.UIFigure);
            app.Step2CreateSourceImpedancePanel.Title = 'Step 2. Create Source Impedance';
            app.FrequencyMHzStartEditFieldLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.FrequencyMHzStartEditFieldLabel.Text = 'Frequency (MHz) Start';
            app.FrequencyMHzStartEditField = uieditfield(app.Step2CreateSourceImpedancePanel, 'numeric');
            app.FrequencyMHzStartEditField.Value = 10;
            app.FrequencyMHzEndEditFieldLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.FrequencyMHzEndEditFieldLabel.Text = 'Frequency (MHz) End';
            app.FrequencyMHzEndEditField = uieditfield(app.Step2CreateSourceImpedancePanel, 'numeric');
            app.FrequencyMHzEndEditField.Value = 250;
            app.ofStepsEditFieldLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.ofStepsEditFieldLabel.Text = '# of Steps';
            app.ofStepsEditField = uieditfield(app.Step2CreateSourceImpedancePanel, 'numeric');
            app.ofStepsEditField.Value = 1001;
            app.SourceTypeDropDownLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.SourceTypeDropDownLabel.Text = 'Source Type';
            app.SourceTypeDropDown = uidropdown(app.Step2CreateSourceImpedancePanel);
            app.SourceTypeDropDown.Items = {'Coil (Inductance)', 'Trap (Capacitance)'};
            app.SourceTypeDropDown.Value = 'Coil (Inductance)';
            app.SourceResistanceOhmEditFieldLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.SourceResistanceOhmEditFieldLabel.Text = 'Source Resistance (Ohm)';
            app.SourceResistanceOhmEditField = uieditfield(app.Step2CreateSourceImpedancePanel, 'numeric');
            app.SourceResistanceOhmEditField.Value = 0.2;
            app.SourceInductanceorCapacitanceLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.SourceInductanceorCapacitanceLabel.Text = {'Source Inductance (uH) '; 'or Capacitance (pF)'};
            app.SourceInductanceuHorCapacitancepFEditField = uieditfield(app.Step2CreateSourceImpedancePanel, 'numeric');
            app.SourceInductanceuHorCapacitancepFEditField.Value = 0.2;
            app.PlotSourceImpedanceButton = uibutton(app.Step2CreateSourceImpedancePanel, 'push');
            app.PlotSourceImpedanceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSourceImpedanceButtonPushed, true);
            app.PlotSourceImpedanceButton.Text = 'Plot Source Impedance';
            app.Step2LoadSourceImpedancePanel = uipanel(app.UIFigure);
            app.Step2LoadSourceImpedancePanel.Title = 'Step 2. Load Source Impedance';
            app.VNACoilFileNameEditFieldLabel = uilabel(app.Step2LoadSourceImpedancePanel);
            app.VNACoilFileNameEditFieldLabel.Text = 'VNA Coil File Name';
            app.VNACoilFileNameEditField = uieditfield(app.Step2LoadSourceImpedancePanel, 'text');
            app.VNACoilFileNameEditField.Value = 'S11_RectLoop_10x6cm_Loaded.csv';
            app.PlotMeasuredSourceImpedanceButton = uibutton(app.Step2LoadSourceImpedancePanel, 'push');
            app.PlotMeasuredSourceImpedanceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMeasuredSourceImpedanceButtonPushed, true);
            app.PlotMeasuredSourceImpedanceButton.Text = 'Plot Measured Source Impedance';
            app.Step3SelectandFineTunetheLCCNetworkPanel = uipanel(app.UIFigure);
            app.Step3SelectandFineTunetheLCCNetworkPanel.Title = 'Step 3. Select and Fine-Tune the LCC Network';
            app.LCCType1Panel = uipanel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.LCCType1Panel.Title = 'LCC  Type1';
            app.Image3 = uiimage(app.LCCType1Panel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'LCC_Type1.png');
            app.DoubleTunedNetworkButtonGroup = uibuttongroup(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.DoubleTunedNetworkButtonGroup.Title = 'Double-Tuned Network';
            app.LCCType1Button = uiradiobutton(app.DoubleTunedNetworkButtonGroup);
            app.LCCType1Button.Text = 'LCC Type 1';
            app.LCCType1Button.Value = true;
            app.LCCType2Button = uiradiobutton(app.DoubleTunedNetworkButtonGroup);
            app.LCCType2Button.Text = 'LCC Type 2';
            app.LCCType2Panel = uipanel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.LCCType2Panel.Title = 'LCC Type2';
            app.Image2 = uiimage(app.LCCType2Panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'LCC_Type2.png');
            app.ClickHeretoPlotLCCNetworkImpedanceButton = uibutton(app.Step3SelectandFineTunetheLCCNetworkPanel, 'push');
            app.ClickHeretoPlotLCCNetworkImpedanceButton.ButtonPushedFcn = createCallbackFcn(app, @ClickHeretoPlotLCCNetworkImpedanceButtonPushed, true);
            app.ClickHeretoPlotLCCNetworkImpedanceButton.Text = {'Click Here to Plot'; 'LCC Network Impedance'};
            app.ReCenterC1Button = uibutton(app.Step3SelectandFineTunetheLCCNetworkPanel, 'push');
            app.ReCenterC1Button.ButtonPushedFcn = createCallbackFcn(app, @ReCenterC1ButtonPushed, true);
            app.ReCenterC1Button.Text = 'Re-Center C1';
            app.ReCenterC2Button = uibutton(app.Step3SelectandFineTunetheLCCNetworkPanel, 'push');
            app.ReCenterC2Button.ButtonPushedFcn = createCallbackFcn(app, @ReCenterC2ButtonPushed, true);
            app.ReCenterC2Button.Text = 'Re-Center C2';
            app.ReCenterL1Button = uibutton(app.Step3SelectandFineTunetheLCCNetworkPanel, 'push');
            app.ReCenterL1Button.ButtonPushedFcn = createCallbackFcn(app, @ReCenterL1ButtonPushed, true);
            app.ReCenterL1Button.Text = 'Re-Center L1';
            app.C1pFEditFieldLabel = uilabel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.C1pFEditFieldLabel.Text = 'C1 (pF)';
            app.C1pFEditField = uieditfield(app.Step3SelectandFineTunetheLCCNetworkPanel, 'numeric');
            app.C1pFEditField.ValueChangedFcn = createCallbackFcn(app, @C1pFEditFieldValueChanged, true);
            app.C1pFEditField.Value = 80;
            app.C1pFSliderLabel = uilabel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.C1pFSliderLabel.Text = 'C1 (pF)';
            app.C1pFSlider = uislider(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.C1pFSlider.Limits = [0 500];
            app.C1pFSlider.ValueChangingFcn = createCallbackFcn(app, @C1pFSliderValueChanging, true);
            app.C1pFSlider.Value = 80;
            app.C2pFEditFieldLabel = uilabel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.C2pFEditFieldLabel.Text = 'C2 (pF)';
            app.C2pFEditField = uieditfield(app.Step3SelectandFineTunetheLCCNetworkPanel, 'numeric');
            app.C2pFEditField.ValueChangedFcn = createCallbackFcn(app, @C2pFEditFieldValueChanged, true);
            app.C2pFEditField.Value = 268;
            app.C2pFSliderLabel = uilabel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.C2pFSliderLabel.Text = 'C2 (pF)';
            app.C2pFSlider = uislider(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.C2pFSlider.Limits = [0 500];
            app.C2pFSlider.ValueChangingFcn = createCallbackFcn(app, @C2pFSliderValueChanging, true);
            app.C2pFSlider.Value = 268;
            app.L1nHEditFieldLabel = uilabel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.L1nHEditFieldLabel.Text = 'L1 (nH)';
            app.L1nHEditField = uieditfield(app.Step3SelectandFineTunetheLCCNetworkPanel, 'numeric');
            app.L1nHEditField.ValueChangedFcn = createCallbackFcn(app, @L1nHEditFieldValueChanged, true);
            app.L1nHEditField.Value = 42;
            app.L1nHSliderLabel = uilabel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.L1nHSliderLabel.Text = 'L1 (nH)';
            app.L1nHSlider = uislider(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.L1nHSlider.Limits = [0 500];
            app.L1nHSlider.ValueChangingFcn = createCallbackFcn(app, @L1nHSliderValueChanging, true);
            app.L1nHSlider.Value = 42;
            app.C1lossOhmEditFieldLabel = uilabel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.C1lossOhmEditFieldLabel.Text = 'C1 loss (Ohm)';
            app.C1lossOhmEditField = uieditfield(app.Step3SelectandFineTunetheLCCNetworkPanel, 'numeric');
            app.C1lossOhmEditField.ValueChangedFcn = createCallbackFcn(app, @C1lossOhmEditFieldValueChanged, true);
            app.C1lossOhmEditField.Value = 0.001;
            app.L1lossOhmEditFieldLabel = uilabel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.L1lossOhmEditFieldLabel.Text = 'L1 loss (Ohm)';
            app.L1lossOhmEditField = uieditfield(app.Step3SelectandFineTunetheLCCNetworkPanel, 'numeric');
            app.L1lossOhmEditField.ValueChangedFcn = createCallbackFcn(app, @L1lossOhmEditFieldValueChanged, true);
            app.L1lossOhmEditField.Value = 0.05;
            app.C2lossOhmEditFieldLabel = uilabel(app.Step3SelectandFineTunetheLCCNetworkPanel);
            app.C2lossOhmEditFieldLabel.Text = 'C2 loss (Ohm)';
            app.C2lossOhmEditField = uieditfield(app.Step3SelectandFineTunetheLCCNetworkPanel, 'numeric');
            app.C2lossOhmEditField.ValueChangedFcn = createCallbackFcn(app, @C2lossOhmEditFieldValueChanged, true);
            app.C2lossOhmEditField.Value = 0.001;
            app.ReCenterYlimitButton = uibutton(app.UIFigure, 'push');
            app.ReCenterYlimitButton.ButtonPushedFcn = createCallbackFcn(app, @ReCenterYlimitButtonPushed, true);
            app.ReCenterYlimitButton.Text = {'Re-Center '; 'Y limit'};
            app.ByChenhaoSunPhDchenhaosunyaleeduVersion11Label = uilabel(app.UIFigure);
            app.ByChenhaoSunPhDchenhaosunyaleeduVersion11Label.Text = 'By: Chenhao Sun, Ph.D., chenhao.sun@yale.edu, Version 1.1';
            app.ApplicationsPanel = uipanel(app.UIFigure);
            app.ApplicationsPanel.Title = 'Applications: ';
            app.Image4_3 = uiimage(app.ApplicationsPanel);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'DT_Coil.png');
            app.Image4 = uiimage(app.ApplicationsPanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'DT_Trap.png');
            app.Image4_2 = uiimage(app.ApplicationsPanel);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'DT_Balun.png');
            app.DoubleTunedRFCoilLabel = uilabel(app.ApplicationsPanel);
            app.DoubleTunedRFCoilLabel.Text = 'Double-Tuned RF Coil';
            app.DoubleTunedTrapCircuitLabel = uilabel(app.ApplicationsPanel);
            app.DoubleTunedTrapCircuitLabel.Text = 'Double-Tuned Trap Circuit';
            app.DoubleTunedCableBalunLabel = uilabel(app.ApplicationsPanel);
            app.DoubleTunedCableBalunLabel.Text = 'Double-Tuned Cable Balun';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoubleTuned_CircuitDesignHelper_V1p1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end