classdef DTDM_V2_Compact < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        ImpedanceMonitoringOptionalPanel  matlab.ui.container.Panel
        MatchedImpedanceNucleus2Label   matlab.ui.control.Label
        MatchedImpedanceNucleus1Label   matlab.ui.control.Label
        SourceImpedanceLabel            matlab.ui.control.Label
        NumberofPointsEditField         matlab.ui.control.NumericEditField
        NumberofPointsEditFieldLabel    matlab.ui.control.Label
        EnableSwitch                    matlab.ui.control.Switch
        EnableSwitchLabel               matlab.ui.control.Label
        MatchedZSmith_Nuc2              matlab.ui.control.UIAxes
        MatchedZSmith_Nuc1              matlab.ui.control.UIAxes
        SourceZSmith                    matlab.ui.control.UIAxes
        Step4LCCNetwork2TuningPanel     matlab.ui.container.Panel
        FineButton_6                    matlab.ui.control.Button
        FineButton_5                    matlab.ui.control.Button
        FineButton_4                    matlab.ui.control.Button
        YlimRecenterButton_2            matlab.ui.control.Button
        YlimSlider_2                    matlab.ui.control.Slider
        YlimEditField_2                 matlab.ui.control.NumericEditField
        YlimEditField_2Label            matlab.ui.control.Label
        NetworkTypeDropDown_2           matlab.ui.control.DropDown
        NetworkTypeDropDown_2Label      matlab.ui.control.Label
        LCC2L1Slider                    matlab.ui.control.Slider
        LCC2C2Slider                    matlab.ui.control.Slider
        LCC2C1Slider                    matlab.ui.control.Slider
        LCC2L1nHEditField               matlab.ui.control.NumericEditField
        LCC2L1nHLabel                   matlab.ui.control.Label
        LCC2C2pFEditField               matlab.ui.control.NumericEditField
        LCC2C2pFEditField_2Label        matlab.ui.control.Label
        LCC2C1pFEditField               matlab.ui.control.NumericEditField
        LCC2C1pFEditFieldLabel          matlab.ui.control.Label
        CoarseButton_6                  matlab.ui.control.Button
        CoarseButton_5                  matlab.ui.control.Button
        CoarseButton_4                  matlab.ui.control.Button
        UIAxes_LCC2                     matlab.ui.control.UIAxes
        Step3InputSingleTunedMatchingNetworkValuePanel  matlab.ui.container.Panel
        SecondNucleusSingleTunedMatchingNetworkPanel  matlab.ui.container.Panel
        SecondNucleusCapShuntpFEditField  matlab.ui.control.NumericEditField
        SecondNucleusCapparallelpFLabel  matlab.ui.control.Label
        SecondNucleusCapseriespFEditField  matlab.ui.control.NumericEditField
        SecondNucleusCapseriespFEditFieldLabel  matlab.ui.control.Label
        SecondNucleusSourceImpedanceLabel  matlab.ui.control.Label
        SecondNucleusSourceImpedanceEditField  matlab.ui.control.EditField
        SecondNucleusEditField          matlab.ui.control.EditField
        FirstNucleusSingleTuendMatchingNetworkPanel  matlab.ui.container.Panel
        FirstNucleusCapShuntpFEditField  matlab.ui.control.NumericEditField
        FirstNucleusCapShuntpFLabel     matlab.ui.control.Label
        FirstNucleusCapseriespFEditField  matlab.ui.control.NumericEditField
        FirstNucleusCapseriespFEditFieldLabel  matlab.ui.control.Label
        FirstNucleusSourceImpedanceLabel  matlab.ui.control.Label
        FirstNucleusSourceImpedanceEditField  matlab.ui.control.EditField
        FirstNucleusEditField           matlab.ui.control.EditField
        LoadImpedanceOhmEditField       matlab.ui.control.NumericEditField
        LoadImpedanceOhmEditFieldLabel  matlab.ui.control.Label
        MatchingnetworktypeDropDown     matlab.ui.control.DropDown
        MatchingnetworktypeDropDownLabel  matlab.ui.control.Label
        PlotMatchingNetworksComponentImpedanceButton  matlab.ui.control.Button
        Step4LCCNetwork1TuningPanel     matlab.ui.container.Panel
        FineButton_3                    matlab.ui.control.Button
        FineButton_2                    matlab.ui.control.Button
        FineButton                      matlab.ui.control.Button
        YlimRecenterButton              matlab.ui.control.Button
        YlimSlider                      matlab.ui.control.Slider
        YlimEditField                   matlab.ui.control.NumericEditField
        YlimEditFieldLabel              matlab.ui.control.Label
        NetworkTypeDropDown             matlab.ui.control.DropDown
        NetworkTypeDropDownLabel        matlab.ui.control.Label
        LCC1L1Slider                    matlab.ui.control.Slider
        LCC1L1nHEditField               matlab.ui.control.NumericEditField
        LCC1L1nHEditFieldLabel          matlab.ui.control.Label
        LCC1C2Slider                    matlab.ui.control.Slider
        LCC1C2pFEditField               matlab.ui.control.NumericEditField
        LCC1C2pFEditFieldLabel          matlab.ui.control.Label
        LCC1C1Slider                    matlab.ui.control.Slider
        LCC1C1pFEditField               matlab.ui.control.NumericEditField
        LCC1C1pFEditFieldLabel          matlab.ui.control.Label
        CoarseButton                    matlab.ui.control.Button
        CoarseButton_2                  matlab.ui.control.Button
        CoarseButton_3                  matlab.ui.control.Button
        UIAxes_LCC1                     matlab.ui.control.UIAxes
        ApplicationsDoubleTunedDoubleMatchedRFCoilPanel  matlab.ui.container.Panel
        LCCType2Label                   matlab.ui.control.Label
        LCCType1Label                   matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        DTDMCoilType2Label              matlab.ui.control.Label
        DTDMCoilType1Label              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        ByChenhaoSunPhDOctober2025chenhaosunyaleeduLabel  matlab.ui.control.Label
        Step2LoadSourceImpedancePanel   matlab.ui.container.Panel
        LoadMeasuredSourceImpedanceButton  matlab.ui.control.Button
        VNACoilFileNameEditField        matlab.ui.control.EditField
        VNACoilFileNameEditFieldLabel   matlab.ui.control.Label
        Step2CreateSourceImpedancePanel  matlab.ui.container.Panel
        CreateSourceImpedanceButton     matlab.ui.control.Button
        SourceInductanceuHEditField     matlab.ui.control.NumericEditField
        SourceInductanceorCapacitanceLabel  matlab.ui.control.Label
        SourceResistanceOhmEditField    matlab.ui.control.NumericEditField
        SourceResistanceOhmEditFieldLabel  matlab.ui.control.Label
        SourceTypeDropDown              matlab.ui.control.DropDown
        SourceTypeDropDownLabel         matlab.ui.control.Label
        ofStepsEditField                matlab.ui.control.NumericEditField
        ofStepsEditFieldLabel           matlab.ui.control.Label
        FrequencyMHzEndEditField        matlab.ui.control.NumericEditField
        FrequencyMHzEndEditFieldLabel   matlab.ui.control.Label
        FrequencyMHzStartEditField      matlab.ui.control.NumericEditField
        FrequencyMHzStartEditFieldLabel  matlab.ui.control.Label
        Step1LarmorFrequencyTablePanel  matlab.ui.container.Panel
        PickTwoLabel                    matlab.ui.control.Label
        FieldStrengthTEditField         matlab.ui.control.NumericEditField
        FieldStrengthTEditFieldLabel    matlab.ui.control.Label
        Color31PColorPicker             matlab.ui.control.ColorPicker
        Color31PColorPickerLabel        matlab.ui.control.Label
        Color19FColorPicker             matlab.ui.control.ColorPicker
        Color19FColorPickerLabel        matlab.ui.control.Label
        Color3HeColorPicker             matlab.ui.control.ColorPicker
        Color3HeColorPickerLabel        matlab.ui.control.Label
        Color129XeColorPicker           matlab.ui.control.ColorPicker
        Color129XeColorPickerLabel      matlab.ui.control.Label
        Color23NaColorPicker            matlab.ui.control.ColorPicker
        Color23NaColorPickerLabel       matlab.ui.control.Label
        Color13CColorPicker             matlab.ui.control.ColorPicker
        Color13CColorPickerLabel        matlab.ui.control.Label
        Color2HColorPicker              matlab.ui.control.ColorPicker
        Color2HColorPickerLabel         matlab.ui.control.Label
        Color1HColorPicker              matlab.ui.control.ColorPicker
        Color1HColorPickerLabel         matlab.ui.control.Label
        Color17OColorPicker             matlab.ui.control.ColorPicker
        Color17OColorPickerLabel        matlab.ui.control.Label
        HMHzEditField                   matlab.ui.control.NumericEditField
        HMHzEditFieldLabel              matlab.ui.control.Label
        HMHzEditField_2                 matlab.ui.control.NumericEditField
        HMHzEditField_2Label            matlab.ui.control.Label
        FMHzEditField                   matlab.ui.control.NumericEditField
        FMHzEditFieldLabel              matlab.ui.control.Label
        HeMHzEditField                  matlab.ui.control.NumericEditField
        HeMHzEditFieldLabel             matlab.ui.control.Label
        PMHzEditField                   matlab.ui.control.NumericEditField
        PMHzEditFieldLabel              matlab.ui.control.Label
        XeMHzEditField                  matlab.ui.control.NumericEditField
        XeMHzEditFieldLabel             matlab.ui.control.Label
        NaMHzEditField                  matlab.ui.control.NumericEditField
        NaMHzEditFieldLabel             matlab.ui.control.Label
        CMHzEditField                   matlab.ui.control.NumericEditField
        CMHzEditFieldLabel              matlab.ui.control.Label
        OMHzEditField                   matlab.ui.control.NumericEditField
        OMHzLabel                       matlab.ui.control.Label
        Mark19FCheckBox                 matlab.ui.control.CheckBox
        Mark3HeCheckBox                 matlab.ui.control.CheckBox
        Mark129XeCheckBox               matlab.ui.control.CheckBox
        Mark23NaCheckBox                matlab.ui.control.CheckBox
        Mark13CCheckBox                 matlab.ui.control.CheckBox
        Mark2HCheckBox                  matlab.ui.control.CheckBox
        Mark31PCheckBox                 matlab.ui.control.CheckBox
        Mark1HCheckBox                  matlab.ui.control.CheckBox
        Mark17OCheckBox                 matlab.ui.control.CheckBox
        ClickheretoInitiateButton       matlab.ui.control.Button
        DoubleTunedDoubleMatchedCircuitDesignHelperforMRRFCoilLabel  matlab.ui.control.Label
    end

    % Copyright 2025 Chenhao Sun
    %
    % Licensed under the Apache License, Version 2.0 (the "License");
    % you may not use this file except in compliance with the License.
    % You may obtain a copy of the License at
    %
    %     http://www.apache.org/licenses/LICENSE-2.0
    %
    % Unless required by applicable law or agreed to in writing, software
    % distributed under the License is distributed on an "AS IS" BASIS,
    % WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
    % See the License for the specific language governing permissions and
    % limitations under the License.

    properties (Access = private)
        % Larmor freq
        FieldStrength 
        LF_17O
        LF_2H
        LF_13C
        LF_23Na
        LF_129Xe
        LF_31P
        LF_3He
        LF_19F
        LF_1H

        NucLabel
        NucAryInd
        NucAryColor
        NucLarmorAry
        NucLarmorIndAry
        
        % freq start and end
        Freq_S
        Freq_E
        Freq_NofS
        F1 % Freq Vector

        R %Source Resistance
        IndCap % Source inductance or capacitance
        Z1 % Source impedance parameters
        max1 % max of source reactance across the freq vector
        min1
       
        Z2% matched to Impedance

        % 
        Zcs_nuc1
        Zcp_nuc1
        Zcs_nuc2
        Zcp_nuc2

        % two UI Axis plot handles
        P1 
        P2
        S1
        S2

        Zc1
        Zl1
        Zc2
        LCC_React

        LCC1_Zc1
        LCC1_Zc2
        LCC1_Zl1
        LCC1_React

        LCC2_Zc1
        LCC2_Zc2
        LCC2_Zl1
        LCC2_React
    end
    
    methods (Access = private)
        
        function [] = Update_LCC1_React(app)
            % Re-calcualte the LCC reactance
            if strcmp(app.NetworkTypeDropDown.Value,'LCC Type 1')
                app.LCC1_React = app.LCC1_Zc1 + 1./( (1./app.LCC1_Zl1) + (1./app.LCC1_Zc2) );
            elseif strcmp(app.NetworkTypeDropDown.Value,'LCC Type 2')
                app.LCC1_React = 1./( (1./(app.LCC1_Zc1 + app.LCC1_Zl1)) + (1./app.LCC1_Zc2) );
            end
        end

        
        function [Zc] = Zcalc_Cap(app,CpF)
            Zc = 1./(1i*2*pi*app.F1*1e6*CpF*1e-12);
        end
        
        function [Zl] = Zcalc_Ind(app,LnH)
            Zl = 1i*2*pi*app.F1*1e6*LnH*1e-9;
        end
       
        
        function [] = UpdateNucleiLabel(app)
            
            if app.Mark17OCheckBox.Value == 1 %17O
                app.NucAryInd(1) = 1;
                app.NucAryColor(1,:) = app.Color17OColorPicker.Value;
                [~,app.NucLarmorIndAry(1)] = min(abs(app.F1-app.LF_17O));
            end
            if app.Mark2HCheckBox.Value == 1 %17O
                app.NucAryInd(2) = 1;
                app.NucAryColor(2,:) = app.Color2HColorPicker.Value;
                [~,app.NucLarmorIndAry(2)] = min(abs(app.F1-app.LF_2H));
            end
            if app.Mark13CCheckBox.Value == 1 %17O
                app.NucAryInd(3) = 1;
                app.NucAryColor(3,:) = app.Color13CColorPicker.Value;
                [~,app.NucLarmorIndAry(3)] = min(abs(app.F1-app.LF_13C));
            end
            if app.Mark23NaCheckBox.Value == 1 %17O
                app.NucAryInd(4) = 1;
                app.NucAryColor(4,:) = app.Color23NaColorPicker.Value;
                [~,app.NucLarmorIndAry(4)] = min(abs(app.F1-app.LF_23Na));
            end
            if app.Mark129XeCheckBox.Value == 1 %17O
                app.NucAryInd(5) = 1;
                app.NucAryColor(5,:) = app.Color129XeColorPicker.Value;
                [~,app.NucLarmorIndAry(5)] = min(abs(app.F1-app.LF_129Xe));
            end
            if app.Mark31PCheckBox.Value == 1 %17O
                app.NucAryInd(6) = 1;
                app.NucAryColor(6,:) = app.Color31PColorPicker.Value;
                [~,app.NucLarmorIndAry(6)] = min(abs(app.F1-app.LF_31P));
            end
            if app.Mark3HeCheckBox.Value == 1 %17O
                app.NucAryInd(7) = 1;
                app.NucAryColor(7,:) = app.Color3HeColorPicker.Value;
                [~,app.NucLarmorIndAry(7)] = min(abs(app.F1-app.LF_3He));
            end
            if app.Mark19FCheckBox.Value == 1 %17O
                app.NucAryInd(8) = 1;
                app.NucAryColor(8,:) = app.Color19FColorPicker.Value;
                [~,app.NucLarmorIndAry(8)] = min(abs(app.F1-app.LF_19F));
            end
            if app.Mark1HCheckBox.Value == 1 %17O
                app.NucAryInd(9) = 1;
                app.NucAryColor(9,:) = app.Color1HColorPicker.Value;
                [~,app.NucLarmorIndAry(9)] = min(abs(app.F1-app.LF_1H));
            end
        
            if sum(app.NucAryInd)>2
                error('Pick more than two Nucleus')
            elseif sum(app.NucAryInd) == 2
                ind1 = find(app.NucAryInd);
                app.FirstNucleusEditField.Value = app.NucLabel( ind1(1) );
                app.FirstNucleusEditField.FontColor = app.NucAryColor( ind1(1),: );
                text1 = num2str(app.Z1(app.NucLarmorIndAry(ind1(1))), '%.4f');
                app.FirstNucleusSourceImpedanceEditField.Value = text1;

                app.SecondNucleusEditField.Value = app.NucLabel( ind1(2) );
                app.SecondNucleusEditField.FontColor = app.NucAryColor( ind1(2),: );
                text2 = num2str(app.Z1(app.NucLarmorIndAry(ind1(2))), '%.4f');
                app.SecondNucleusSourceImpedanceEditField.Value = text2;

            elseif sum(app.NucAryInd) < 2
                error('pick less than two Nucleus')
            end


        end
        
        function [] = PlotMNImpedance(app)
        %     if app.Mark1HCheckBox.Value == 1
        %         [Err,ind1H] = min(abs(app.F1-app.LF_1H));
        %         if Err>=ErrLimit
        %             error('Error in Marking Nucleus Frequency')
        %         end
        %         M1H=plot(app.UIAxes_LCC1,app.F1(ind1H),app.Z1(ind1H),'.','MarkerSize', 16,'Color',app.Color1HColorPicker.Value);
        %         text(app.UIAxes_LCC1,app.F1(ind1H)+2,app.Z1(ind1H),'1H','Color',app.Color1HColorPicker.Value);
        %         set(get(get(M1H,'Annotation'),'LegendInformation'), 'IconDisplayStyle', 'off');
        %     end
        %     hold(app.UIAxes_LCC1,'off')
            ErrLimit = 0.5;
            hold(app.UIAxes_LCC1,'on')
            hold(app.UIAxes_LCC2,'on')
            localmaxLCC1 =[];
            localmaxLCC2 =[];

                % ind1 = find(app.NucAryInd);
                % app.FirstNucleusEditField.Value = app.NucLabel( ind1(1) );
                % app.FirstNucleusEditField.FontColor = app.NucAryColor( ind1(1),: );
                % text1 = num2str(app.Z1(app.NucLarmorIndAry(ind1(1))), '%.4f');
                % app.FirstNucleusSourceImpedanceEditField.Value = text1;
                % 
                % app.SecondNucleusEditField.Value = app.NucLabel( ind1(2) );
                % app.SecondNucleusEditField.FontColor = app.NucAryColor( ind1(2),: );
                % text2 = num2str(app.Z1(app.NucLarmorIndAry(ind1(2))), '%.4f');
                % app.SecondNucleusSourceImpedanceEditField.Value = text2;

            ind1 = find(app.NucAryInd);
            Freq_ind1 = app.NucLarmorIndAry(ind1(1)); Freq_ind2 = app.NucLarmorIndAry(ind1(2));
            if strcmp(app.MatchingnetworktypeDropDown.Value,'Coil Type 1 (Series LCC + Shunt LCC)')
                %LCC1 Nucleus 1
                plot(app.UIAxes_LCC1,app.F1(Freq_ind1),imag(app.Zcs_nuc1(Freq_ind1)),'.','MarkerSize', 16,'Color',app.NucAryColor(ind1(1),:));
                text(app.UIAxes_LCC1,app.F1(Freq_ind1)+2,imag(app.Zcs_nuc1(Freq_ind1)),app.NucLabel(ind1(1)),'Color',app.NucAryColor(ind1(1),:));
                localmaxLCC1 = cat(2, localmaxLCC1 , imag(app.Zcs_nuc1(Freq_ind1)) );
                %LCC1 Nucleus 2
                plot(app.UIAxes_LCC1,app.F1(Freq_ind2),imag(app.Zcs_nuc2(Freq_ind2)),'.','MarkerSize', 16,'Color',app.NucAryColor(ind1(2),:));
                text(app.UIAxes_LCC1,app.F1(Freq_ind2)+2,imag(app.Zcs_nuc2(Freq_ind2)),app.NucLabel(ind1(2)),'Color',app.NucAryColor(ind1(2),:));
                localmaxLCC1 = cat(2, localmaxLCC1 , imag(app.Zcs_nuc2(Freq_ind2)) );
                %LCC2 Nucleus 1
                plot(app.UIAxes_LCC2,app.F1(Freq_ind1),imag(app.Zcp_nuc1(Freq_ind1)),'.','MarkerSize', 16,'Color',app.NucAryColor(ind1(1),:));
                text(app.UIAxes_LCC2,app.F1(Freq_ind1)+2,imag(app.Zcp_nuc1(Freq_ind1)),app.NucLabel(ind1(1)),'Color',app.NucAryColor(ind1(1),:));
                localmaxLCC2 = cat(2, localmaxLCC2 , imag(app.Zcp_nuc1(Freq_ind1)) );
                %LCC2 Nucleus 2
                plot(app.UIAxes_LCC2,app.F1(Freq_ind2),imag(app.Zcp_nuc2(Freq_ind2)),'.','MarkerSize', 16,'Color',app.NucAryColor(ind1(2),:));
                text(app.UIAxes_LCC2,app.F1(Freq_ind2)+2,imag(app.Zcp_nuc2(Freq_ind2)),app.NucLabel(ind1(2)),'Color',app.NucAryColor(ind1(2),:));
                localmaxLCC2 = cat(2, localmaxLCC2 , imag(app.Zcp_nuc2(Freq_ind2)) );

            elseif strcmp(app.MatchingnetworktypeDropDown.Value,'Coil Type 2 (Shunt LCC + Series LCC)') 
                %LCC1 Nucleus 1
                plot(app.UIAxes_LCC1,app.F1(Freq_ind1),imag(app.Zcp_nuc1(Freq_ind1)),'.','MarkerSize', 16,'Color',app.NucAryColor(ind1(1),:));
                text(app.UIAxes_LCC1,app.F1(Freq_ind1)+2,imag(app.Zcp_nuc1(Freq_ind1)),app.NucLabel(ind1(1)),'Color',app.NucAryColor(ind1(1),:));
                localmaxLCC1 = cat(2, localmaxLCC1 , imag(app.Zcp_nuc1(Freq_ind1)) );
                %LCC1 Nucleus 2
                plot(app.UIAxes_LCC1,app.F1(Freq_ind2),imag(app.Zcp_nuc2(Freq_ind2)),'.','MarkerSize', 16,'Color',app.NucAryColor(ind1(2),:));
                text(app.UIAxes_LCC1,app.F1(Freq_ind2)+2,imag(app.Zcp_nuc2(Freq_ind2)),app.NucLabel(ind1(2)),'Color',app.NucAryColor(ind1(2),:));
                localmaxLCC1 = cat(2, localmaxLCC1 , imag(app.Zcp_nuc2(Freq_ind2)) );
                %LCC2 Nucleus 1
                plot(app.UIAxes_LCC2,app.F1(Freq_ind1),imag(app.Zcs_nuc1(Freq_ind1)),'.','MarkerSize', 16,'Color',app.NucAryColor(ind1(1),:));
                text(app.UIAxes_LCC2,app.F1(Freq_ind1)+2,imag(app.Zcs_nuc1(Freq_ind1)),app.NucLabel(ind1(1)),'Color',app.NucAryColor(ind1(1),:));
                localmaxLCC2 = cat(2, localmaxLCC2 , imag(app.Zcs_nuc1(Freq_ind1)) );
                %LCC2 Nucleus 2
                plot(app.UIAxes_LCC2,app.F1(Freq_ind2),imag(app.Zcs_nuc2(Freq_ind2)),'.','MarkerSize', 16,'Color',app.NucAryColor(ind1(2),:));
                text(app.UIAxes_LCC2,app.F1(Freq_ind2)+2,imag(app.Zcs_nuc2(Freq_ind2)),app.NucLabel(ind1(2)),'Color',app.NucAryColor(ind1(2),:));
                localmaxLCC2 = cat(2, localmaxLCC2 , imag(app.Zcs_nuc2(Freq_ind2)) );


            end


            % for i1 = 1:9
            % 
            %     if strcmp(app.MatchingnetworktypeDropDown.Value,'Coil Type 1 (Series LCC + Shunt LCC)')
            %         if app.NucAryInd(i1) == 1
            %             [Err,ind_nuc1] = min(abs(app.F1-app.NucLarmorAry(i1)));
            %             if Err>=ErrLimit
            %               error('Error in finding nucleus larmor frequency')
            %             end
            %             % plot LCC network 1
            %             plot(app.UIAxes_LCC1,app.F1(ind_nuc1),imag(app.Zcs_nuc1(ind_nuc1)),'.','MarkerSize', 16,'Color',app.NucAryColor(i1,:));
            %             text(app.UIAxes_LCC1,app.F1(ind_nuc1)+2,imag(app.Zcs_nuc1(ind_nuc1)),app.NucLabel(i1),'Color',app.NucAryColor(i1,:));
            %             localmaxLCC1 = cat(2, localmaxLCC1 , imag(app.Zcs_nuc1(ind_nuc1)) );
            %             % plot LCC network 2
            %             plot(app.UIAxes_LCC2,app.F1(ind_nuc1),imag(app.Zcp_nuc1(ind_nuc1)),'.','MarkerSize', 16,'Color',app.NucAryColor(i1,:));
            %             text(app.UIAxes_LCC2,app.F1(ind_nuc1)+2,imag(app.Zcp_nuc1(ind_nuc1)),app.NucLabel(i1),'Color',app.NucAryColor(i1,:));
            %             localmaxLCC2 = cat(2, localmaxLCC2 , imag(app.Zcp_nuc1(ind_nuc1)) );
            %         end
            % 
            %     elseif strcmp(app.MatchingnetworktypeDropDown.Value,'Coil Type 2 (Shunt LCC + Series LCC)')                    
            %         if app.NucAryInd(i1) == 1
            %             [Err,ind_nuc1] = min(abs(app.F1-app.NucLarmorAry(i1)));
            %             if Err>=ErrLimit
            %               error('Error in finding nucleus larmor frequency')
            %             end
            %             % plot LCC network 1
            %             plot(app.UIAxes_LCC1,app.F1(ind_nuc1),imag(app.Zcp_nuc1(ind_nuc1)),'.','MarkerSize', 16,'Color',app.NucAryColor(i1,:));
            %             text(app.UIAxes_LCC1,app.F1(ind_nuc1)+2,imag(app.Zcp_nuc1(ind_nuc1)),app.NucLabel(i1),'Color',app.NucAryColor(i1,:));
            %             localmaxLCC1 = cat(2, localmaxLCC1 , imag(app.Zcp_nuc1(ind_nuc1)) );
            %             % plot LCC network 2
            %             plot(app.UIAxes_LCC2,app.F1(ind_nuc1),imag(app.Zcs_nuc1(ind_nuc1)),'.','MarkerSize', 16,'Color',app.NucAryColor(i1,:));
            %             text(app.UIAxes_LCC2,app.F1(ind_nuc1)+2,imag(app.Zcs_nuc1(ind_nuc1)),app.NucLabel(i1),'Color',app.NucAryColor(i1,:));
            %             localmaxLCC2 = cat(2, localmaxLCC2 , imag(app.Zcs_nuc1(ind_nuc1)) );
            %         end
            % 
            % 
            %     end
            % 
            % end
            xlim(app.UIAxes_LCC1,[min(app.F1) max(app.F1)])
            ylim(app.UIAxes_LCC1,[min(localmaxLCC1)*2 0])
            xlim(app.UIAxes_LCC2,[min(app.F1) max(app.F1)])
            ylim(app.UIAxes_LCC2,[min(localmaxLCC2)*2 0])
        end
        
        function [] = Update_LCC2_React(app)
            if strcmp(app.NetworkTypeDropDown_2.Value,'LCC Type 1')
                app.LCC2_React = app.LCC2_Zc1 + 1./( (1./app.LCC2_Zl1) + (1./app.LCC2_Zc2) );
            elseif strcmp(app.NetworkTypeDropDown_2.Value,'LCC Type 2')
                app.LCC2_React = 1./( (1./(app.LCC2_Zc1 + app.LCC2_Zl1)) + (1./app.LCC2_Zc2) );
            end
        end

        
        function [] = PlotSmith_SourceZ(app)
            hold(app.SourceZSmith,'on')
            smithplot(app.SourceZSmith,app.F1,z2gamma(app.Z1));
       
            for i2 = 1:9
                if app.NucAryInd(i2) == 1
                    hold(app.SourceZSmith,'on')
                    smithplot(app.SourceZSmith,app.F1(app.NucLarmorIndAry(i2)),z2gamma(app.Z1(app.NucLarmorIndAry(i2))),...
                        '.','Color',app.NucAryColor(i2,:),'MarkerSize', 18);
                    text(app.SourceZSmith,real(z2gamma(app.Z1(app.NucLarmorIndAry(i2))))-0.2,imag(z2gamma(app.Z1(app.NucLarmorIndAry(i2))))-0.2,...
                        app.NucLabel(i2), 'Color',app.NucAryColor(i2,:));

                end
            end
            % hold(app.SourceZSmith,'on')
            % title(app.SourceZSmith,'Source Impedance')
        end
        
        function [] = PlotSmith_MatchedZ(app)

            if strcmp(app.EnableSwitch.Value,'On')  
                if strcmp(app.MatchingnetworktypeDropDown.Value,'Coil Type 1 (Series LCC + Shunt LCC)')
                    Z_step1 = app.Z1 + app.LCC1_React;
                    app.Z2 = 1./( (1./(Z_step1)) + (1./(app.LCC2_React))  );
                elseif strcmp(app.MatchingnetworktypeDropDown.Value,'Coil Type 2 (Shunt LCC + Series LCC)') 
                    Z_setp1 = 1./( (1./(app.Z1)) + (1./(app.LCC1_React))  );
                    app.Z2 = Z_setp1 + app.LCC2_React;
                end
    
                DisPts = app.NumberofPointsEditField.Value;
                ind1 = find(app.NucAryInd);
                Freq_ind1 = app.NucLarmorIndAry(ind1(1)); Freq_ind2 = app.NucLarmorIndAry(ind1(2));
                
                % for Nucleus 1
                smithplot(app.MatchedZSmith_Nuc1,app.F1(Freq_ind1-DisPts:Freq_ind1+DisPts),z2gamma(app.Z2(Freq_ind1-DisPts:Freq_ind1+DisPts)));
                hold(app.MatchedZSmith_Nuc1,'on')
                smithplot(app.MatchedZSmith_Nuc1,app.F1(Freq_ind1),z2gamma(app.Z2(Freq_ind1)),...
                     '.','Color',app.NucAryColor(ind1(1),:),'MarkerSize', 18);
                text(app.MatchedZSmith_Nuc1,real(z2gamma(app.Z2(Freq_ind1)))-0.2,imag(z2gamma(app.Z2(Freq_ind1)))-0.2,...
                      app.NucLabel(ind1(1)), 'Color',app.NucAryColor(ind1(1),:));
                hold(app.MatchedZSmith_Nuc1,'off')
                title(app.MatchedZSmith_Nuc1,'Matched Impedance Nucleus1')

                % for Nucleus 2
                smithplot(app.MatchedZSmith_Nuc2,app.F1(Freq_ind2-DisPts:Freq_ind2+DisPts),z2gamma(app.Z2(Freq_ind2-DisPts:Freq_ind2+DisPts)));
                hold(app.MatchedZSmith_Nuc2,'on')
                smithplot(app.MatchedZSmith_Nuc2,app.F1(Freq_ind2),z2gamma(app.Z2(Freq_ind2)),...
                     '.','Color',app.NucAryColor(ind1(2),:),'MarkerSize', 18);
                text(app.MatchedZSmith_Nuc2,real(z2gamma(app.Z2(Freq_ind2)))-0.2,imag(z2gamma(app.Z2(Freq_ind2)))-0.2,...
                      app.NucLabel(ind1(2)), 'Color',app.NucAryColor(ind1(2),:));
                hold(app.MatchedZSmith_Nuc2,'off')


                % % hold(app.MatchedZSmith_Nuc1,'on')
                % smithplot(app.MatchedZSmith_Nuc1,app.F1,z2gamma(app.Z2));
                % 
                % for i2 = 1:9
                %     if app.NucAryInd(i2) == 1
                %         hold(app.MatchedZSmith_Nuc1,'on')
                %         smithplot(app.MatchedZSmith_Nuc1,app.F1(app.NucLarmorIndAry(i2)),z2gamma(app.Z2(app.NucLarmorIndAry(i2))),...
                %             '.','Color',app.NucAryColor(i2,:),'MarkerSize', 18);
                %         text(app.MatchedZSmith_Nuc1,real(z2gamma(app.Z2(app.NucLarmorIndAry(i2))))-0.2,imag(z2gamma(app.Z2(app.NucLarmorIndAry(i2))))-0.2,...
                %             app.NucLabel(i2), 'Color',app.NucAryColor(i2,:));
                % 
                %     end
                % end
                % hold(app.MatchedZSmith_Nuc1,'off')
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClickheretoInitiateButton
        function ClickheretoInitiateButtonPushed(app, event)
            
            clc 

            app.Z1 = [];
            app.F1 = [];

            cla(app.UIAxes_LCC1 ,'reset')
            cla(app.UIAxes_LCC2 ,'reset')
            cla(app.SourceZSmith ,'reset')
            cla(app.MatchedZSmith_Nuc1 ,'reset')
            cla(app.MatchedZSmith_Nuc2 ,'reset')

            
            title(app.UIAxes_LCC1,'Target Reactance vs LCC Network Reactance')
            xlabel(app.UIAxes_LCC1,'Frequency Range in MHz')
            ylabel(app.UIAxes_LCC1,'Reactance')
            title(app.UIAxes_LCC2,'Target Reactance vs LCC Network Reactance')
            xlabel(app.UIAxes_LCC2,'Frequency Range in MHz')
            ylabel(app.UIAxes_LCC2,'Reactance')
            
            % title(app.SourceZSmith,'Source Impedance')
            % title(app.MatchedZSmith_Nuc1,'Matched Impedance Nucleus1')
            % title(app.MatchedZSmith_Nuc2,'Matched Impedance Nucleus2')

            app.FieldStrength = app.FieldStrengthTEditField.Value;

            app.LF_17O = app.FieldStrength*5.772;
            app.OMHzEditField.Value = app.LF_17O;

            app.LF_2H = app.FieldStrength*6.536;
            app.HMHzEditField_2.Value = app.LF_2H;

            app.LF_13C = app.FieldStrength*10.7084;
            app.CMHzEditField.Value = app.LF_13C;

            app.LF_23Na = app.FieldStrength*11.262;
            app.NaMHzEditField.Value = app.LF_23Na;

            app.LF_129Xe = app.FieldStrength*11.7767338;
            app.XeMHzEditField.Value = app.LF_129Xe;

            app.LF_31P = app.FieldStrength*17.235;
            app.PMHzEditField.Value = app.LF_31P;

            app.LF_3He = app.FieldStrength*32.434100033;
            app.HeMHzEditField.Value = app.LF_3He;

            app.LF_19F = app.FieldStrength*40.078;
            app.FMHzEditField.Value = app.LF_19F;

            app.LF_1H = app.FieldStrength*42.577478461;
            app.HMHzEditField.Value = app.LF_1H;

            app.NucLabel = ["17O", "2H","13C","23Na","129Xe","31P","3He","19F","1H"];
            app.NucAryInd = zeros(1,9);
            app.NucLarmorAry = [app.LF_17O app.LF_2H app.LF_13C app.LF_23Na app.LF_129Xe...
                app.LF_31P app.LF_3He app.LF_19F app.LF_1H];


            % app.NetworkTypeDropDown_2.Value = 'LCC Type 2';
            app.NetworkTypeDropDown.ValueIndex = 1;
            app.NetworkTypeDropDown_2.ValueIndex = 1;
        end

        % Value changed function: FieldStrengthTEditField
        function FieldStrengthTEditFieldValueChanged(app, event)
            app.FieldStrength = app.FieldStrengthTEditField.Value;

            app.LF_17O = app.FieldStrength*5.772;
            app.OMHzEditField.Value = app.LF_17O;

            app.LF_2H = app.FieldStrength*6.536;
            app.HMHzEditField_2.Value = app.LF_2H;

            app.LF_13C = app.FieldStrength*10.7084;
            app.CMHzEditField.Value = app.LF_13C;

            app.LF_23Na = app.FieldStrength*11.262;
            app.NaMHzEditField.Value = app.LF_23Na;

            app.LF_129Xe = app.FieldStrength*11.7767338;
            app.XeMHzEditField.Value = app.LF_129Xe;

            app.LF_31P = app.FieldStrength*17.235;
            app.PMHzEditField.Value = app.LF_31P;

            app.LF_3He = app.FieldStrength*32.434100033;
            app.HeMHzEditField.Value = app.LF_3He;

            app.LF_19F = app.FieldStrength*40.078;
            app.FMHzEditField.Value = app.LF_19F;

            app.LF_1H = app.FieldStrength*42.577478461;
            app.HMHzEditField.Value = app.LF_1H;

            app.NucLarmorAry = [app.LF_17O app.LF_2H app.LF_13C app.LF_23Na app.LF_129Xe...
                app.LF_31P app.LF_3He app.LF_19F app.LF_1H];
            
        end

        % Button pushed function: LoadMeasuredSourceImpedanceButton
        function LoadMeasuredSourceImpedanceButtonPushed(app, event)
            T = readtable(app.VNACoilFileNameEditField.Value, 'HeaderLines',1);
            T = T{:,:};
            app.F1 = T(:,1)*1e-6;
            Gammas_Z(:,1) = T(:,2) .* exp(1i*T(:,3)*pi/180) ;
            app.Z1(:,1) = ( gamma2z(Gammas_Z(:,1)) );

            UpdateNucleiLabel(app);

            PlotSmith_SourceZ(app)
        end

        % Button pushed function: CreateSourceImpedanceButton
        function CreateSourceImpedanceButtonPushed(app, event)
            
            app.Freq_S = app.FrequencyMHzStartEditField.Value; 
            app.Freq_E = app.FrequencyMHzEndEditField.Value; 
            app.Freq_NofS = app.ofStepsEditField.Value; 
            app.IndCap = app.SourceInductanceuHEditField.Value;


            app.F1 = linspace(app.Freq_S, app.Freq_E, app.Freq_NofS); % in MHz

            if strcmp(app.SourceTypeDropDown.Value,'Coil (Inductance)')
                app.Z1 = 2*pi*app.F1*1e6*app.IndCap*1e-6;
            % elseif strcmp(app.SourceTypeDropDown.Value,'Trap (Capacitance)')
            %     app.Z1 = 1./(2*pi*app.F1*1e6*app.IndCap*1e-12);
            end

            UpdateNucleiLabel(app);

            PlotSmith_SourceZ(app)

        end

        % Button pushed function: 
        % PlotMatchingNetworksComponentImpedanceButton
        function PlotMatchingNetworksComponentImpedanceButtonPushed(app, event)
            app.Zcs_nuc1 = Zcalc_Cap(app,app.FirstNucleusCapseriespFEditField.Value);
            app.Zcp_nuc1 = Zcalc_Cap(app,app.FirstNucleusCapShuntpFEditField.Value);
            app.Zcs_nuc2 = Zcalc_Cap(app,app.SecondNucleusCapseriespFEditField.Value);
            app.Zcp_nuc2 = Zcalc_Cap(app,app.SecondNucleusCapShuntpFEditField.Value);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % plot the individual frequency's MN Impedance
            PlotMNImpedance(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.LCC1_Zc1 = Zcalc_Cap(app,app.LCC1C1pFEditField.Value);
            app.LCC1_Zc2 = Zcalc_Cap(app,app.LCC1C2pFEditField.Value);
            app.LCC1_Zl1 = Zcalc_Ind(app,app.LCC1L1nHEditField.Value);
            Update_LCC1_React(app)

            app.LCC2_Zc1 = Zcalc_Cap(app,app.LCC2C1pFEditField.Value);
            app.LCC2_Zc2 = Zcalc_Cap(app,app.LCC2C2pFEditField.Value);
            app.LCC2_Zl1 = Zcalc_Ind(app,app.LCC2L1nHEditField.Value);
            Update_LCC2_React(app)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % LCC reactance first plot here
            hold(app.UIAxes_LCC1,'on')
            app.P1 = plot(app.UIAxes_LCC1,app.F1,imag(app.LCC1_React));
            % legend(app.MatchedZSmith_Nuc2,[app.R1 app.R2],'Source Reactance','LCC Reactance')
            % hold(app.MatchedZSmith_Nuc2,'off')

            hold(app.UIAxes_LCC1,'on')
            app.P2 = plot(app.UIAxes_LCC2,app.F1,imag(app.LCC2_React));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % plot matched impedance
            PlotSmith_MatchedZ(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        end

        % Value changed function: LCC1C1pFEditField
        function LCC1C1pFEditFieldValueChanged(app, event)
            value = app.LCC1C1pFEditField.Value;
            app.LCC1_Zc1 = Zcalc_Cap(app,value);
            Update_LCC1_React(app)
            hold(app.UIAxes_LCC1,'on');
            set(app.P1, 'YData', imag(app.LCC1_React))
            drawnow

            app.LCC1C1Slider.Limits = [value*0.5,value*1.5];
            app.LCC1C1Slider.Value = value;

            PlotSmith_MatchedZ(app)
        end

        % Value changing function: LCC1C1Slider
        function LCC1C1SliderValueChanging(app, event)
            % changingValue = event.Value;
            changingValue = event.Value;
            app.LCC1C1pFEditField.Value = changingValue;

            app.LCC1_Zc1 = Zcalc_Cap(app,changingValue);
            Update_LCC1_React(app)
            hold(app.UIAxes_LCC1,'on')
            set(app.P1, 'YData', imag(app.LCC1_React))
            drawnow  

            PlotSmith_MatchedZ(app)
        end

        % Button pushed function: CoarseButton
        function CoarseButtonPushed(app, event)
            app.LCC1C1Slider.Limits = [app.LCC1C1pFEditField.Value*0.5,app.LCC1C1pFEditField.Value*1.5];
        end

        % Button pushed function: FineButton
        function FineButtonPushed(app, event)
            app.LCC1C1Slider.Limits = [app.LCC1C1pFEditField.Value*0.9,app.LCC1C1pFEditField.Value*1.1];
        end

        % Value changed function: LCC1C2pFEditField
        function LCC1C2pFEditFieldValueChanged(app, event)
            value = app.LCC1C2pFEditField.Value;
            app.LCC1_Zc2 = Zcalc_Cap(app,value);
            Update_LCC1_React(app)
            hold(app.UIAxes_LCC1,'on');
            set(app.P1, 'YData', imag(app.LCC1_React))
            drawnow

            app.LCC1C2Slider.Limits = [value*0.5,value*1.5];
            app.LCC1C2Slider.Value = value;
            
            PlotSmith_MatchedZ(app)
        end

        % Value changing function: LCC1C2Slider
        function LCC1C2SliderValueChanging(app, event)
            % changingValue = event.Value;
            changingValue = event.Value;
            app.LCC1C2pFEditField.Value = changingValue;

            app.LCC1_Zc2 = Zcalc_Cap(app,changingValue);
            Update_LCC1_React(app)
            hold(app.UIAxes_LCC1,'on')
            set(app.P1, 'YData', imag(app.LCC1_React))
            drawnow  

            PlotSmith_MatchedZ(app)
        end

        % Button pushed function: CoarseButton_2
        function CoarseButton_2Pushed(app, event)
            app.LCC1C2Slider.Limits = [app.LCC1C2pFEditField.Value*0.5,app.LCC1C2pFEditField.Value*1.5];
        end

        % Button pushed function: FineButton_2
        function FineButton_2Pushed(app, event)
            app.LCC1C2Slider.Limits = [app.LCC1C2pFEditField.Value*0.9,app.LCC1C2pFEditField.Value*1.1];
        end

        % Value changed function: LCC1L1nHEditField
        function LCC1L1nHEditFieldValueChanged(app, event)
            % value = app.LCC1L1nHEditField.Value;
            value = app.LCC1L1nHEditField.Value;
            app.LCC1_Zl1 = Zcalc_Ind(app,value);
            Update_LCC1_React(app)
            hold(app.UIAxes_LCC1,'on');
            set(app.P1, 'YData', imag(app.LCC1_React))
            drawnow

            app.LCC1L1Slider.Limits = [value*0.5,value*1.5];
            app.LCC1L1Slider.Value = value;

            PlotSmith_MatchedZ(app)
        end

        % Value changing function: LCC1L1Slider
        function LCC1L1SliderValueChanging(app, event)
            % changingValue = event.Value;
            changingValue = event.Value;
            app.LCC1L1nHEditField.Value = changingValue;

            app.LCC1_Zl1 = Zcalc_Ind(app,changingValue);
            Update_LCC1_React(app)
            hold(app.UIAxes_LCC1,'on')
            set(app.P1, 'YData', imag(app.LCC1_React))
            drawnow  
            
            PlotSmith_MatchedZ(app)
        end

        % Button pushed function: CoarseButton_3
        function CoarseButton_3Pushed(app, event)
            app.LCC1L1Slider.Limits = [app.LCC1L1nHEditField.Value*0.5,app.LCC1L1nHEditField.Value*1.5];
        end

        % Button pushed function: FineButton_3
        function FineButton_3Pushed(app, event)
            app.LCC1L1Slider.Limits = [app.LCC1L1nHEditField.Value*0.9,app.LCC1L1nHEditField.Value*1.1];
        end

        % Value changed function: YlimEditField
        function YlimEditFieldValueChanged(app, event)
            value = app.YlimEditField.Value;
            app.YlimSlider.Limits = [0,value*2];
            app.YlimSlider.Value = value; 
            
            ylim(app.UIAxes_LCC1,[-value value*0.25])
            
        end

        % Value changing function: YlimSlider
        function YlimSliderValueChanging(app, event)
            changingValue = event.Value;
            app.YlimEditField.Value = changingValue;
            
            ylim(app.UIAxes_LCC1,[-changingValue changingValue*0.25])
        end

        % Button pushed function: YlimRecenterButton
        function YlimRecenterButtonPushed(app, event)
            app.YlimSlider.Limits = [0,app.YlimSlider.Value*2];
        end

        % Value changed function: LCC2C1pFEditField
        function LCC2C1pFEditFieldValueChanged(app, event)
            % value = app.LCC2C1pFEditField.Value;
            value = app.LCC2C1pFEditField.Value;
            app.LCC2_Zc1 = Zcalc_Cap(app,value);
            Update_LCC2_React(app)
            hold(app.UIAxes_LCC2,'on');
            set(app.P2, 'YData', imag(app.LCC2_React))
            drawnow

            app.LCC2C1Slider.Limits = [value*0.5,value*1.5];
            app.LCC2C1Slider.Value = value;

            PlotSmith_MatchedZ(app)
        end

        % Value changing function: LCC2C1Slider
        function LCC2C1SliderValueChanging(app, event)
            % changingValue = event.Value;
            changingValue = event.Value;
            app.LCC2C1pFEditField.Value = changingValue;

            app.LCC2_Zc1 = Zcalc_Cap(app,changingValue);
            Update_LCC2_React(app)
            hold(app.UIAxes_LCC2,'on')
            set(app.P2, 'YData', imag(app.LCC2_React))
            drawnow  

            PlotSmith_MatchedZ(app)
        end

        % Button pushed function: CoarseButton_4
        function CoarseButton_4Pushed(app, event)
            app.LCC2C1Slider.Limits = [app.LCC2C1pFEditField.Value*0.5,app.LCC2C1pFEditField.Value*1.5];
        end

        % Button pushed function: FineButton_4
        function FineButton_4Pushed(app, event)
            app.LCC2C1Slider.Limits = [app.LCC2C1pFEditField.Value*0.9,app.LCC2C1pFEditField.Value*1.1];
        end

        % Value changed function: LCC2C2pFEditField
        function LCC2C2pFEditFieldValueChanged(app, event)
            % value = app.LCC2C2pFEditField.Value;
            value = app.LCC2C2pFEditField.Value;
            app.LCC2_Zc2 = Zcalc_Cap(app,value);
            Update_LCC2_React(app)
            hold(app.UIAxes_LCC2,'on');
            set(app.P2, 'YData', imag(app.LCC2_React))
            drawnow

            app.LCC2C2Slider.Limits = [value*0.5,value*1.5];
            app.LCC2C2Slider.Value = value;
            
            PlotSmith_MatchedZ(app)
        end

        % Value changing function: LCC2C2Slider
        function LCC2C2SliderValueChanging(app, event)
            % changingValue = event.Value;
            changingValue = event.Value;
            app.LCC2C2pFEditField.Value = changingValue;

            app.LCC2_Zc2 = Zcalc_Cap(app,changingValue);
            Update_LCC2_React(app)
            hold(app.UIAxes_LCC2,'on')
            set(app.P2, 'YData', imag(app.LCC2_React))
            drawnow  

            PlotSmith_MatchedZ(app)
        end

        % Button pushed function: CoarseButton_5
        function CoarseButton_5Pushed(app, event)
            app.LCC2C2Slider.Limits = [app.LCC2C2pFEditField.Value*0.5,app.LCC2C2pFEditField.Value*1.5];
        end

        % Button pushed function: FineButton_5
        function FineButton_5Pushed(app, event)
            app.LCC2C2Slider.Limits = [app.LCC2C2pFEditField.Value*0.9,app.LCC2C2pFEditField.Value*1.1];
        end

        % Value changed function: LCC2L1nHEditField
        function LCC2L1nHEditFieldValueChanged(app, event)
            % value = app.LCC2L1nHEditField.Value;
            value = app.LCC2L1nHEditField.Value;
            app.LCC2_Zl1 = Zcalc_Ind(app,value);
            Update_LCC2_React(app)
            hold(app.UIAxes_LCC2,'on');
            set(app.P2, 'YData', imag(app.LCC2_React))
            drawnow

            app.LCC2L1Slider.Limits = [value*0.5,value*1.5];
            app.LCC2L1Slider.Value = value;

            PlotSmith_MatchedZ(app)
        end

        % Value changing function: LCC2L1Slider
        function LCC2L1SliderValueChanging(app, event)
            % changingValue = event.Value;
            changingValue = event.Value;
            app.LCC2L1nHEditField.Value = changingValue;

            app.LCC2_Zl1 = Zcalc_Ind(app,changingValue);
            Update_LCC2_React(app)
            hold(app.UIAxes_LCC2,'on')
            set(app.P2, 'YData', imag(app.LCC2_React))
            drawnow  

            PlotSmith_MatchedZ(app)
        end

        % Button pushed function: CoarseButton_6
        function CoarseButton_6Pushed(app, event)
            app.LCC2L1Slider.Limits = [app.LCC2L1nHEditField.Value*0.5,app.LCC2L1nHEditField.Value*1.5];
        end

        % Button pushed function: FineButton_6
        function FineButton_6Pushed(app, event)
            app.LCC2L1Slider.Limits = [app.LCC2L1nHEditField.Value*0.9,app.LCC2L1nHEditField.Value*1.1];
        end

        % Value changed function: YlimEditField_2
        function YlimEditField_2ValueChanged(app, event)
            % value = app.YlimEditField_2.Value;
            value = app.YlimEditField_2.Value;
            app.YlimSlider_2.Limits = [0,value*2];
            app.YlimSlider_2.Value = value; 
            
            ylim(app.UIAxes_LCC2,[-value value*0.25])

        end

        % Value changing function: YlimSlider_2
        function YlimSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.YlimEditField_2.Value = changingValue;
            
            ylim(app.UIAxes_LCC2,[-changingValue changingValue*0.25])
        end

        % Button pushed function: YlimRecenterButton_2
        function YlimRecenterButton_2Pushed(app, event)
            app.YlimSlider_2.Limits = [0,app.YlimSlider_2.Value*2];
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            value = app.EnableSwitch.Value;
            if strcmp(value,'On')
                PlotSmith_MatchedZ(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoubleTunedDoubleMatchedCircuitDesignHelperforMRRFCoilLabel = uilabel(app.UIFigure);
            app.DoubleTunedDoubleMatchedCircuitDesignHelperforMRRFCoilLabel.Text = {'Double-Tuned / Double-Matched'; 'Circuit Design Helper for MR RF Coil'};
            app.ClickheretoInitiateButton = uibutton(app.UIFigure, 'push');
            app.ClickheretoInitiateButton.ButtonPushedFcn = createCallbackFcn(app, @ClickheretoInitiateButtonPushed, true);
            app.ClickheretoInitiateButton.Text = 'Click here to Initiate';
            app.Step1LarmorFrequencyTablePanel = uipanel(app.UIFigure);
            app.Step1LarmorFrequencyTablePanel.Title = 'Step1. Larmor Frequency Table';
            app.Mark17OCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark17OCheckBox.Text = 'Mark 17O';
            app.Mark1HCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark1HCheckBox.Text = 'Mark 1H';
            app.Mark31PCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark31PCheckBox.Text = 'Mark 31P';
            app.Mark2HCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark2HCheckBox.Text = 'Mark 2H';
            app.Mark13CCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark13CCheckBox.Text = 'Mark 13C';
            app.Mark23NaCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark23NaCheckBox.Text = 'Mark 23Na';
            app.Mark129XeCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark129XeCheckBox.Text = 'Mark 129Xe';
            app.Mark3HeCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark3HeCheckBox.Text = 'Mark 3He';
            app.Mark19FCheckBox = uicheckbox(app.Step1LarmorFrequencyTablePanel);
            app.Mark19FCheckBox.Text = 'Mark 19F';
            app.OMHzLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.OMHzLabel.Text = '17O (MHz)';
            app.OMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.OMHzEditField.Value = 27.1284;
            app.CMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.CMHzEditFieldLabel.Text = '13C (MHz)';
            app.CMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.CMHzEditField.Value = 50.32948;
            app.NaMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.NaMHzEditFieldLabel.Text = '23Na (MHz)';
            app.NaMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.NaMHzEditField.Value = 52.9314;
            app.XeMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.XeMHzEditFieldLabel.Text = '129Xe (MHz)';
            app.XeMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.XeMHzEditField.Value = 55.35064886;
            app.PMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.PMHzEditFieldLabel.Text = '31P (MHz)';
            app.PMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.PMHzEditField.Value = 81.0045;
            app.HeMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.HeMHzEditFieldLabel.Text = '3He (MHz)';
            app.HeMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.HeMHzEditField.Value = 152.4402701551;
            app.FMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.FMHzEditFieldLabel.Text = '19F (MHz)';
            app.FMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.FMHzEditField.Value = 188.3666;
            app.HMHzEditField_2Label = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.HMHzEditField_2Label.Text = '2H (MHz)';
            app.HMHzEditField_2 = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.HMHzEditField_2.Value = 30.7192;
            app.HMHzEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.HMHzEditFieldLabel.Text = '1H (MHz)';
            app.HMHzEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.HMHzEditField.Value = 200.1141487667;
            app.Color17OColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color17OColorPickerLabel.Text = 'Color 17O  ';
            app.Color17OColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color17OColorPicker.Value = [1 0 1];
            app.Color1HColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color1HColorPickerLabel.Text = 'Color 1H';
            app.Color1HColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color2HColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color2HColorPickerLabel.Text = 'Color 2H';
            app.Color2HColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color2HColorPicker.Value = [0.52156862745098 0.0862745098039216 0.819607843137255];
            app.Color13CColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color13CColorPickerLabel.Text = 'Color 13C';
            app.Color13CColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color13CColorPicker.Value = [0.650980392156863 0.650980392156863 0.650980392156863];
            app.Color23NaColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color23NaColorPickerLabel.Text = 'Color 23Na';
            app.Color23NaColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color23NaColorPicker.Value = [0.96078431372549 0.466666666666667 0.16078431372549];
            app.Color129XeColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color129XeColorPickerLabel.Text = 'Color 129Xe';
            app.Color129XeColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color129XeColorPicker.Value = [1 1 0];
            app.Color3HeColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color3HeColorPickerLabel.Text = 'Color 3He';
            app.Color3HeColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color3HeColorPicker.Value = [0.286274509803922 0.858823529411765 0.250980392156863];
            app.Color19FColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color19FColorPickerLabel.Text = 'Color 19F';
            app.Color19FColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color19FColorPicker.Value = [0.423529411764706 0.956862745098039 1];
            app.Color31PColorPickerLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.Color31PColorPickerLabel.Text = 'Color 31P';
            app.Color31PColorPicker = uicolorpicker(app.Step1LarmorFrequencyTablePanel);
            app.Color31PColorPicker.Value = [0 0 1];
            app.FieldStrengthTEditFieldLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.FieldStrengthTEditFieldLabel.Text = 'Field Strength (T)';
            app.FieldStrengthTEditField = uieditfield(app.Step1LarmorFrequencyTablePanel, 'numeric');
            app.FieldStrengthTEditField.ValueChangedFcn = createCallbackFcn(app, @FieldStrengthTEditFieldValueChanged, true);
            app.FieldStrengthTEditField.Value = 4.7;
            app.PickTwoLabel = uilabel(app.Step1LarmorFrequencyTablePanel);
            app.PickTwoLabel.Text = ' Pick Two';
            app.Step2CreateSourceImpedancePanel = uipanel(app.UIFigure);
            app.Step2CreateSourceImpedancePanel.Title = 'Step 2. Create Source Impedance';
            app.FrequencyMHzStartEditFieldLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.FrequencyMHzStartEditFieldLabel.Text = 'Frequency (MHz) Start';
            app.FrequencyMHzStartEditField = uieditfield(app.Step2CreateSourceImpedancePanel, 'numeric');
            app.FrequencyMHzStartEditField.Value = 1;
            app.FrequencyMHzEndEditFieldLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.FrequencyMHzEndEditFieldLabel.Text = 'Frequency (MHz) End';
            app.FrequencyMHzEndEditField = uieditfield(app.Step2CreateSourceImpedancePanel, 'numeric');
            app.FrequencyMHzEndEditField.Value = 150;
            app.ofStepsEditFieldLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.ofStepsEditFieldLabel.Text = '# of Steps';
            app.ofStepsEditField = uieditfield(app.Step2CreateSourceImpedancePanel, 'numeric');
            app.ofStepsEditField.Value = 201;
            app.SourceTypeDropDownLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.SourceTypeDropDownLabel.Text = 'Source Type';
            app.SourceTypeDropDown = uidropdown(app.Step2CreateSourceImpedancePanel);
            app.SourceTypeDropDown.Items = {'Coil (Inductance)'};
            app.SourceTypeDropDown.Value = 'Coil (Inductance)';
            app.SourceResistanceOhmEditFieldLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.SourceResistanceOhmEditFieldLabel.Text = 'Source Resistance (Ohm)';
            app.SourceResistanceOhmEditField = uieditfield(app.Step2CreateSourceImpedancePanel, 'numeric');
            app.SourceResistanceOhmEditField.Value = 0.2;
            app.SourceInductanceorCapacitanceLabel = uilabel(app.Step2CreateSourceImpedancePanel);
            app.SourceInductanceorCapacitanceLabel.Text = 'Source Inductance (uH)';
            app.SourceInductanceuHEditField = uieditfield(app.Step2CreateSourceImpedancePanel, 'numeric');
            app.SourceInductanceuHEditField.Value = 1;
            app.CreateSourceImpedanceButton = uibutton(app.Step2CreateSourceImpedancePanel, 'push');
            app.CreateSourceImpedanceButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSourceImpedanceButtonPushed, true);
            app.CreateSourceImpedanceButton.Text = 'Create Source  Impedance';
            app.Step2LoadSourceImpedancePanel = uipanel(app.UIFigure);
            app.Step2LoadSourceImpedancePanel.Title = 'Step 2. Load Source Impedance';
            app.VNACoilFileNameEditFieldLabel = uilabel(app.Step2LoadSourceImpedancePanel);
            app.VNACoilFileNameEditFieldLabel.Text = 'VNA Coil File Name';
            app.VNACoilFileNameEditField = uieditfield(app.Step2LoadSourceImpedancePanel, 'text');
            app.VNACoilFileNameEditField.Value = 'S11_RectLoop_10x6cm_Loaded.csv';
            app.LoadMeasuredSourceImpedanceButton = uibutton(app.Step2LoadSourceImpedancePanel, 'push');
            app.LoadMeasuredSourceImpedanceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMeasuredSourceImpedanceButtonPushed, true);
            app.LoadMeasuredSourceImpedanceButton.Text = 'Load Measured Source Impedance';
            app.ByChenhaoSunPhDOctober2025chenhaosunyaleeduLabel = uilabel(app.UIFigure);
            app.ByChenhaoSunPhDOctober2025chenhaosunyaleeduLabel.Text = 'By: Chenhao Sun, Ph.D., October 2025, chenhao.sun@yale.edu';
            app.ApplicationsDoubleTunedDoubleMatchedRFCoilPanel = uipanel(app.UIFigure);
            app.ApplicationsDoubleTunedDoubleMatchedRFCoilPanel.Title = 'Applications: Double-Tuned Double-Matched RF Coil';
            app.Image_2 = uiimage(app.ApplicationsDoubleTunedDoubleMatchedRFCoilPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'DTDM_Coil2.png');
            app.Image = uiimage(app.ApplicationsDoubleTunedDoubleMatchedRFCoilPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'DTDM_Coil.png');
            app.DTDMCoilType1Label = uilabel(app.ApplicationsDoubleTunedDoubleMatchedRFCoilPanel);
            app.DTDMCoilType1Label.Text = {'DTDM Coil '; 'Type 1'};
            app.DTDMCoilType2Label = uilabel(app.ApplicationsDoubleTunedDoubleMatchedRFCoilPanel);
            app.DTDMCoilType2Label.Text = {'DTDM Coil '; 'Type 2'};
            app.Image2 = uiimage(app.ApplicationsDoubleTunedDoubleMatchedRFCoilPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'LCC_Type1.png');
            app.Image2_2 = uiimage(app.ApplicationsDoubleTunedDoubleMatchedRFCoilPanel);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'LCC_Type2.png');
            app.LCCType1Label = uilabel(app.ApplicationsDoubleTunedDoubleMatchedRFCoilPanel);
            app.LCCType1Label.Text = 'LCC Type1';
            app.LCCType2Label = uilabel(app.ApplicationsDoubleTunedDoubleMatchedRFCoilPanel);
            app.LCCType2Label.Text = 'LCC Type2';
            app.Step4LCCNetwork1TuningPanel = uipanel(app.UIFigure);
            app.Step4LCCNetwork1TuningPanel.Title = 'Step 4. LCC Network 1 Tuning';
            app.UIAxes_LCC1 = uiaxes(app.Step4LCCNetwork1TuningPanel);
            title(app.UIAxes_LCC1, 'Target Reactance vs LCC Network Reactance')
            xlabel(app.UIAxes_LCC1, 'Frequency Range in MHz')
            ylabel(app.UIAxes_LCC1, 'Reactance')
            zlabel(app.UIAxes_LCC1, 'Z')
            app.CoarseButton_3 = uibutton(app.Step4LCCNetwork1TuningPanel, 'push');
            app.CoarseButton_3.ButtonPushedFcn = createCallbackFcn(app, @CoarseButton_3Pushed, true);
            app.CoarseButton_3.Text = 'Coarse';
            app.CoarseButton_2 = uibutton(app.Step4LCCNetwork1TuningPanel, 'push');
            app.CoarseButton_2.ButtonPushedFcn = createCallbackFcn(app, @CoarseButton_2Pushed, true);
            app.CoarseButton_2.Text = 'Coarse';
            app.CoarseButton = uibutton(app.Step4LCCNetwork1TuningPanel, 'push');
            app.CoarseButton.ButtonPushedFcn = createCallbackFcn(app, @CoarseButtonPushed, true);
            app.CoarseButton.Text = 'Coarse';
            app.LCC1C1pFEditFieldLabel = uilabel(app.Step4LCCNetwork1TuningPanel);
            app.LCC1C1pFEditFieldLabel.Text = 'LCC1 C1(pF)';
            app.LCC1C1pFEditField = uieditfield(app.Step4LCCNetwork1TuningPanel, 'numeric');
            app.LCC1C1pFEditField.ValueChangedFcn = createCallbackFcn(app, @LCC1C1pFEditFieldValueChanged, true);
            app.LCC1C1pFEditField.Value = 47.07;
            app.LCC1C1Slider = uislider(app.Step4LCCNetwork1TuningPanel);
            app.LCC1C1Slider.Limits = [0 500];
            app.LCC1C1Slider.ValueChangingFcn = createCallbackFcn(app, @LCC1C1SliderValueChanging, true);
            app.LCC1C1Slider.Value = 47.07;
            app.LCC1C2pFEditFieldLabel = uilabel(app.Step4LCCNetwork1TuningPanel);
            app.LCC1C2pFEditFieldLabel.Text = 'LCC1 C2(pF)';
            app.LCC1C2pFEditField = uieditfield(app.Step4LCCNetwork1TuningPanel, 'numeric');
            app.LCC1C2pFEditField.ValueChangedFcn = createCallbackFcn(app, @LCC1C2pFEditFieldValueChanged, true);
            app.LCC1C2pFEditField.Value = 317.6;
            app.LCC1C2Slider = uislider(app.Step4LCCNetwork1TuningPanel);
            app.LCC1C2Slider.Limits = [0 500];
            app.LCC1C2Slider.ValueChangingFcn = createCallbackFcn(app, @LCC1C2SliderValueChanging, true);
            app.LCC1C2Slider.Value = 317.6;
            app.LCC1L1nHEditFieldLabel = uilabel(app.Step4LCCNetwork1TuningPanel);
            app.LCC1L1nHEditFieldLabel.Text = 'LCC1 L1(nH)';
            app.LCC1L1nHEditField = uieditfield(app.Step4LCCNetwork1TuningPanel, 'numeric');
            app.LCC1L1nHEditField.ValueChangedFcn = createCallbackFcn(app, @LCC1L1nHEditFieldValueChanged, true);
            app.LCC1L1nHEditField.Value = 67.63;
            app.LCC1L1Slider = uislider(app.Step4LCCNetwork1TuningPanel);
            app.LCC1L1Slider.Limits = [0 500];
            app.LCC1L1Slider.ValueChangingFcn = createCallbackFcn(app, @LCC1L1SliderValueChanging, true);
            app.LCC1L1Slider.Value = 67.63;
            app.NetworkTypeDropDownLabel = uilabel(app.Step4LCCNetwork1TuningPanel);
            app.NetworkTypeDropDownLabel.Text = 'Network Type';
            app.NetworkTypeDropDown = uidropdown(app.Step4LCCNetwork1TuningPanel);
            app.NetworkTypeDropDown.Items = {'LCC Type 1', 'LCC Type 2'};
            app.NetworkTypeDropDown.Value = 'LCC Type 1';
            app.YlimEditFieldLabel = uilabel(app.Step4LCCNetwork1TuningPanel);
            app.YlimEditFieldLabel.Text = 'Ylim';
            app.YlimEditField = uieditfield(app.Step4LCCNetwork1TuningPanel, 'numeric');
            app.YlimEditField.ValueChangedFcn = createCallbackFcn(app, @YlimEditFieldValueChanged, true);
            app.YlimEditField.Value = 90;
            app.YlimSlider = uislider(app.Step4LCCNetwork1TuningPanel);
            app.YlimSlider.Limits = [0 200];
            app.YlimSlider.ValueChangingFcn = createCallbackFcn(app, @YlimSliderValueChanging, true);
            app.YlimSlider.Value = 90;
            app.YlimRecenterButton = uibutton(app.Step4LCCNetwork1TuningPanel, 'push');
            app.YlimRecenterButton.ButtonPushedFcn = createCallbackFcn(app, @YlimRecenterButtonPushed, true);
            app.YlimRecenterButton.Text = {'Ylim '; 'Re-center'};
            app.FineButton = uibutton(app.Step4LCCNetwork1TuningPanel, 'push');
            app.FineButton.ButtonPushedFcn = createCallbackFcn(app, @FineButtonPushed, true);
            app.FineButton.Text = 'Fine';
            app.FineButton_2 = uibutton(app.Step4LCCNetwork1TuningPanel, 'push');
            app.FineButton_2.ButtonPushedFcn = createCallbackFcn(app, @FineButton_2Pushed, true);
            app.FineButton_2.Text = 'Fine';
            app.FineButton_3 = uibutton(app.Step4LCCNetwork1TuningPanel, 'push');
            app.FineButton_3.ButtonPushedFcn = createCallbackFcn(app, @FineButton_3Pushed, true);
            app.FineButton_3.Text = 'Fine';
            app.Step3InputSingleTunedMatchingNetworkValuePanel = uipanel(app.UIFigure);
            app.Step3InputSingleTunedMatchingNetworkValuePanel.Title = 'Step 3. Input Single-Tuned Matching Network Value';
            app.PlotMatchingNetworksComponentImpedanceButton = uibutton(app.Step3InputSingleTunedMatchingNetworkValuePanel, 'push');
            app.PlotMatchingNetworksComponentImpedanceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMatchingNetworksComponentImpedanceButtonPushed, true);
            app.PlotMatchingNetworksComponentImpedanceButton.Text = 'Plot Matching Network''s Component Impedance';
            app.MatchingnetworktypeDropDownLabel = uilabel(app.Step3InputSingleTunedMatchingNetworkValuePanel);
            app.MatchingnetworktypeDropDownLabel.Text = 'Matching network type';
            app.MatchingnetworktypeDropDown = uidropdown(app.Step3InputSingleTunedMatchingNetworkValuePanel);
            app.MatchingnetworktypeDropDown.Items = {'Coil Type 1 (Series LCC + Shunt LCC)', 'Coil Type 2 (Shunt LCC + Series LCC)'};
            app.MatchingnetworktypeDropDown.Value = 'Coil Type 1 (Series LCC + Shunt LCC)';
            app.LoadImpedanceOhmEditFieldLabel = uilabel(app.Step3InputSingleTunedMatchingNetworkValuePanel);
            app.LoadImpedanceOhmEditFieldLabel.Text = 'Load Impedance (Ohm)';
            app.LoadImpedanceOhmEditField = uieditfield(app.Step3InputSingleTunedMatchingNetworkValuePanel, 'numeric');
            app.LoadImpedanceOhmEditField.Value = 50;
            app.FirstNucleusSingleTuendMatchingNetworkPanel = uipanel(app.Step3InputSingleTunedMatchingNetworkValuePanel);
            app.FirstNucleusSingleTuendMatchingNetworkPanel.Title = 'First Nucleus''  Single-Tuend Matching Network';
            app.FirstNucleusEditField = uieditfield(app.FirstNucleusSingleTuendMatchingNetworkPanel, 'text');
            app.FirstNucleusEditField.Value = '2H';
            app.FirstNucleusSourceImpedanceEditField = uieditfield(app.FirstNucleusSingleTuendMatchingNetworkPanel, 'text');
            app.FirstNucleusSourceImpedanceLabel = uilabel(app.FirstNucleusSingleTuendMatchingNetworkPanel);
            app.FirstNucleusSourceImpedanceLabel.Text = 'First Nucleus Source Impedance';
            app.FirstNucleusCapseriespFEditFieldLabel = uilabel(app.FirstNucleusSingleTuendMatchingNetworkPanel);
            app.FirstNucleusCapseriespFEditFieldLabel.Text = 'First Nucleus Cap series (pF)';
            app.FirstNucleusCapseriespFEditField = uieditfield(app.FirstNucleusSingleTuendMatchingNetworkPanel, 'numeric');
            app.FirstNucleusCapseriespFEditField.Value = 116;
            app.FirstNucleusCapShuntpFLabel = uilabel(app.FirstNucleusSingleTuendMatchingNetworkPanel);
            app.FirstNucleusCapShuntpFLabel.Text = 'First Nucleus Cap Shunt (pF)';
            app.FirstNucleusCapShuntpFEditField = uieditfield(app.FirstNucleusSingleTuendMatchingNetworkPanel, 'numeric');
            app.FirstNucleusCapShuntpFEditField.Value = 597.1;
            app.SecondNucleusSingleTunedMatchingNetworkPanel = uipanel(app.Step3InputSingleTunedMatchingNetworkValuePanel);
            app.SecondNucleusSingleTunedMatchingNetworkPanel.Title = 'Second Nucleus'' Single-Tuned Matching Network';
            app.SecondNucleusEditField = uieditfield(app.SecondNucleusSingleTunedMatchingNetworkPanel, 'text');
            app.SecondNucleusEditField.Value = '23Na';
            app.SecondNucleusSourceImpedanceEditField = uieditfield(app.SecondNucleusSingleTunedMatchingNetworkPanel, 'text');
            app.SecondNucleusSourceImpedanceLabel = uilabel(app.SecondNucleusSingleTunedMatchingNetworkPanel);
            app.SecondNucleusSourceImpedanceLabel.Text = 'Second Nucleus Source Impedance';
            app.SecondNucleusCapseriespFEditFieldLabel = uilabel(app.SecondNucleusSingleTunedMatchingNetworkPanel);
            app.SecondNucleusCapseriespFEditFieldLabel.Text = 'Second Nucleus Cap series (pF)';
            app.SecondNucleusCapseriespFEditField = uieditfield(app.SecondNucleusSingleTunedMatchingNetworkPanel, 'numeric');
            app.SecondNucleusCapseriespFEditField.Value = 37.1;
            app.SecondNucleusCapparallelpFLabel = uilabel(app.SecondNucleusSingleTunedMatchingNetworkPanel);
            app.SecondNucleusCapparallelpFLabel.Text = 'Second Nucleus Cap Shunt (pF)';
            app.SecondNucleusCapShuntpFEditField = uieditfield(app.SecondNucleusSingleTunedMatchingNetworkPanel, 'numeric');
            app.SecondNucleusCapShuntpFEditField.Value = 215.9;
            app.Step4LCCNetwork2TuningPanel = uipanel(app.UIFigure);
            app.Step4LCCNetwork2TuningPanel.Title = 'Step 4. LCC Network 2 Tuning';
            app.UIAxes_LCC2 = uiaxes(app.Step4LCCNetwork2TuningPanel);
            title(app.UIAxes_LCC2, 'Target Reactance vs LCC Network Reactance')
            xlabel(app.UIAxes_LCC2, 'Frequency Range in MHz')
            ylabel(app.UIAxes_LCC2, 'Reactance')
            zlabel(app.UIAxes_LCC2, 'Z')
            app.CoarseButton_4 = uibutton(app.Step4LCCNetwork2TuningPanel, 'push');
            app.CoarseButton_4.ButtonPushedFcn = createCallbackFcn(app, @CoarseButton_4Pushed, true);
            app.CoarseButton_4.Text = 'Coarse';
            app.CoarseButton_5 = uibutton(app.Step4LCCNetwork2TuningPanel, 'push');
            app.CoarseButton_5.ButtonPushedFcn = createCallbackFcn(app, @CoarseButton_5Pushed, true);
            app.CoarseButton_5.Text = 'Coarse';
            app.CoarseButton_6 = uibutton(app.Step4LCCNetwork2TuningPanel, 'push');
            app.CoarseButton_6.ButtonPushedFcn = createCallbackFcn(app, @CoarseButton_6Pushed, true);
            app.CoarseButton_6.Text = 'Coarse';
            app.LCC2C1pFEditFieldLabel = uilabel(app.Step4LCCNetwork2TuningPanel);
            app.LCC2C1pFEditFieldLabel.Text = 'LCC2 C1(pF)';
            app.LCC2C1pFEditField = uieditfield(app.Step4LCCNetwork2TuningPanel, 'numeric');
            app.LCC2C1pFEditField.ValueChangedFcn = createCallbackFcn(app, @LCC2C1pFEditFieldValueChanged, true);
            app.LCC2C1pFEditField.Value = 369.5;
            app.LCC2C2pFEditField_2Label = uilabel(app.Step4LCCNetwork2TuningPanel);
            app.LCC2C2pFEditField_2Label.Text = 'LCC2 C2(pF)';
            app.LCC2C2pFEditField = uieditfield(app.Step4LCCNetwork2TuningPanel, 'numeric');
            app.LCC2C2pFEditField.ValueChangedFcn = createCallbackFcn(app, @LCC2C2pFEditFieldValueChanged, true);
            app.LCC2C2pFEditField.Value = 1180;
            app.LCC2L1nHLabel = uilabel(app.Step4LCCNetwork2TuningPanel);
            app.LCC2L1nHLabel.Text = 'LCC2 L1(nH)';
            app.LCC2L1nHEditField = uieditfield(app.Step4LCCNetwork2TuningPanel, 'numeric');
            app.LCC2L1nHEditField.ValueChangedFcn = createCallbackFcn(app, @LCC2L1nHEditFieldValueChanged, true);
            app.LCC2L1nHEditField.Value = 12.9;
            app.LCC2C1Slider = uislider(app.Step4LCCNetwork2TuningPanel);
            app.LCC2C1Slider.Limits = [0 500];
            app.LCC2C1Slider.ValueChangingFcn = createCallbackFcn(app, @LCC2C1SliderValueChanging, true);
            app.LCC2C1Slider.Value = 369.5;
            app.LCC2C2Slider = uislider(app.Step4LCCNetwork2TuningPanel);
            app.LCC2C2Slider.Limits = [0 2000];
            app.LCC2C2Slider.ValueChangingFcn = createCallbackFcn(app, @LCC2C2SliderValueChanging, true);
            app.LCC2C2Slider.Value = 1180;
            app.LCC2L1Slider = uislider(app.Step4LCCNetwork2TuningPanel);
            app.LCC2L1Slider.Limits = [0 500];
            app.LCC2L1Slider.ValueChangingFcn = createCallbackFcn(app, @LCC2L1SliderValueChanging, true);
            app.LCC2L1Slider.Value = 12.9;
            app.NetworkTypeDropDown_2Label = uilabel(app.Step4LCCNetwork2TuningPanel);
            app.NetworkTypeDropDown_2Label.Text = 'Network Type';
            app.NetworkTypeDropDown_2 = uidropdown(app.Step4LCCNetwork2TuningPanel);
            app.NetworkTypeDropDown_2.Items = {'LCC Type 1', 'LCC Type 2'};
            app.NetworkTypeDropDown_2.Value = 'LCC Type 1';
            app.YlimEditField_2Label = uilabel(app.Step4LCCNetwork2TuningPanel);
            app.YlimEditField_2Label.Text = 'Ylim';
            app.YlimEditField_2 = uieditfield(app.Step4LCCNetwork2TuningPanel, 'numeric');
            app.YlimEditField_2.ValueChangedFcn = createCallbackFcn(app, @YlimEditField_2ValueChanged, true);
            app.YlimEditField_2.Value = 20;
            app.YlimSlider_2 = uislider(app.Step4LCCNetwork2TuningPanel);
            app.YlimSlider_2.ValueChangingFcn = createCallbackFcn(app, @YlimSlider_2ValueChanging, true);
            app.YlimSlider_2.Value = 20;
            app.YlimRecenterButton_2 = uibutton(app.Step4LCCNetwork2TuningPanel, 'push');
            app.YlimRecenterButton_2.ButtonPushedFcn = createCallbackFcn(app, @YlimRecenterButton_2Pushed, true);
            app.YlimRecenterButton_2.Text = {'Ylim '; 'Re-center'};
            app.FineButton_4 = uibutton(app.Step4LCCNetwork2TuningPanel, 'push');
            app.FineButton_4.ButtonPushedFcn = createCallbackFcn(app, @FineButton_4Pushed, true);
            app.FineButton_4.Text = 'Fine';
            app.FineButton_5 = uibutton(app.Step4LCCNetwork2TuningPanel, 'push');
            app.FineButton_5.ButtonPushedFcn = createCallbackFcn(app, @FineButton_5Pushed, true);
            app.FineButton_5.Text = 'Fine';
            app.FineButton_6 = uibutton(app.Step4LCCNetwork2TuningPanel, 'push');
            app.FineButton_6.ButtonPushedFcn = createCallbackFcn(app, @FineButton_6Pushed, true);
            app.FineButton_6.Text = 'Fine';
            app.ImpedanceMonitoringOptionalPanel = uipanel(app.UIFigure);
            app.ImpedanceMonitoringOptionalPanel.Title = 'Impedance Monitoring (Optional)';
            app.SourceZSmith = uiaxes(app.ImpedanceMonitoringOptionalPanel);
            xlabel(app.SourceZSmith, 'X')
            ylabel(app.SourceZSmith, 'Y')
            zlabel(app.SourceZSmith, 'Z')
            app.MatchedZSmith_Nuc1 = uiaxes(app.ImpedanceMonitoringOptionalPanel);
            xlabel(app.MatchedZSmith_Nuc1, 'X')
            ylabel(app.MatchedZSmith_Nuc1, 'Y')
            zlabel(app.MatchedZSmith_Nuc1, 'Z')
            app.MatchedZSmith_Nuc2 = uiaxes(app.ImpedanceMonitoringOptionalPanel);
            xlabel(app.MatchedZSmith_Nuc2, 'X')
            ylabel(app.MatchedZSmith_Nuc2, 'Y')
            zlabel(app.MatchedZSmith_Nuc2, 'Z')
            app.EnableSwitchLabel = uilabel(app.ImpedanceMonitoringOptionalPanel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.ImpedanceMonitoringOptionalPanel, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.NumberofPointsEditFieldLabel = uilabel(app.ImpedanceMonitoringOptionalPanel);
            app.NumberofPointsEditFieldLabel.Text = 'Number of +/- Points ';
            app.NumberofPointsEditField = uieditfield(app.ImpedanceMonitoringOptionalPanel, 'numeric');
            app.NumberofPointsEditField.Value = 10;
            app.SourceImpedanceLabel = uilabel(app.ImpedanceMonitoringOptionalPanel);
            app.SourceImpedanceLabel.Text = 'Source Impedance';
            app.MatchedImpedanceNucleus1Label = uilabel(app.ImpedanceMonitoringOptionalPanel);
            app.MatchedImpedanceNucleus1Label.Text = 'Matched Impedance Nucleus1';
            app.MatchedImpedanceNucleus2Label = uilabel(app.ImpedanceMonitoringOptionalPanel);
            app.MatchedImpedanceNucleus2Label.Text = 'Matched Impedance Nucleus2';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DTDM_V2_Compact

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end